classdef basicActiveSensorEditorGUI_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        basicActiveSensorEditorGUI  matlab.ui.Figure
        GridLayout                  matlab.ui.container.GridLayout
        uipanel1                    matlab.ui.container.Panel
        GridLayout2                 matlab.ui.container.GridLayout
        maxAngErrSlider             matlab.ui.control.Slider
        text5                       matlab.ui.control.Label
        maxRangeErrSlider           matlab.ui.control.Slider
        text6                       matlab.ui.control.Label
        maxConeAngleSlider          matlab.ui.control.Slider
        text4                       matlab.ui.control.Label
        sensorRangeSlider           matlab.ui.control.Slider
        text3                       matlab.ui.control.Label
        text2                       matlab.ui.control.Label
    end

    
    methods (Access = private)
        function handles = setupGUI(app, sensor, handles)
                %Set Up Scroll Bars
                app.sensorRangeSlider.Limits = [0.0, NNS_BasicActiveSensor.maxAllowableMaxRange];
                app.sensorRangeSlider.Value = sensor.maxRng;
                app.sensorRangeSlider.MajorTicks = [app.sensorRangeSlider.Limits(1) : NNS_BasicActiveSensor.maxAllowableMaxRange/5 : app.sensorRangeSlider.Limits(2)];
                app.sensorRangeSlider.MinorTicks = [app.sensorRangeSlider.Limits(1) : NNS_BasicActiveSensor.maxAllowableMaxRange/20 : app.sensorRangeSlider.Limits(2)];
%                 [t, fH] = createDecimalSlider(handles.sensorRangeSlider.Parent, 1E-3, handles.sensorRangeSlider.Position, 0.0, NNS_BasicActiveSensor.maxAllowableMaxRange, sensor.maxRng, NNS_BasicActiveSensor.maxAllowableMaxRange/5, NNS_BasicActiveSensor.maxAllowableMaxRange/20);
%                 delete(handles.sensorRangeSlider);
%                 handles.sensorRangeSlider = t;
%                 t.JavaComponent.StateChangedCallback = @(app,eventData) sensorRangeSlider_Callback(app,eventData,handles);
            
                app.maxConeAngleSlider.Limits = [0.0, rad2deg(NNS_BasicActiveSensor.maxAllowableMaxConeAngle)];
                app.maxConeAngleSlider.Value = rad2deg(sensor.maxConeAngle);
                app.maxConeAngleSlider.MajorTicks = [0.0 : 30 : rad2deg(NNS_BasicActiveSensor.maxAllowableMaxConeAngle)];;
                app.maxConeAngleSlider.MinorTicks = [0.0 : 5  : rad2deg(NNS_BasicActiveSensor.maxAllowableMaxConeAngle)];;
%                 [t, fH] = createDecimalSlider(handles.maxConeAngleSlider.Parent, 1E-3, handles.maxConeAngleSlider.Position, 0.0, rad2deg(NNS_BasicActiveSensor.maxAllowableMaxConeAngle), rad2deg(sensor.maxConeAngle), 30, 5);
%                 delete(handles.maxConeAngleSlider);
%                 handles.maxConeAngleSlider = t;
%                 t.JavaComponent.StateChangedCallback = @(app,eventData) maxConeAngleSlider_Callback(app,eventData,handles);
            
                app.maxRangeErrSlider.Limits = [0.0, 100*NNS_BasicActiveSensor.maxAllowableThreeSigRngDevPercent];
                app.maxRangeErrSlider.Value = 100*sensor.threeSigRngDevPercent;
                app.maxRangeErrSlider.MajorTicks = [0.0 : 20*NNS_BasicActiveSensor.maxAllowableThreeSigRngDevPercent : 100*NNS_BasicActiveSensor.maxAllowableThreeSigRngDevPercent];
                app.maxRangeErrSlider.MinorTicks = [0.0 : 05*NNS_BasicActiveSensor.maxAllowableThreeSigRngDevPercent : 100*NNS_BasicActiveSensor.maxAllowableThreeSigRngDevPercent];
%                 [t, fH] = createDecimalSlider(handles.maxRangeErrSlider.Parent, 1E-3, handles.maxRangeErrSlider.Position, 0.0, 100*NNS_BasicActiveSensor.maxAllowableThreeSigRngDevPercent, 100*sensor.threeSigRngDevPercent, 20*NNS_BasicActiveSensor.maxAllowableThreeSigRngDevPercent, 5*NNS_BasicActiveSensor.maxAllowableThreeSigRngDevPercent);
%                 delete(handles.maxRangeErrSlider);
%                 handles.maxRangeErrSlider = t;
%                 t.JavaComponent.StateChangedCallback = @(app,eventData) maxRangeErrSlider_Callback(app,eventData,handles);
            
                app.maxAngErrSlider.Limits = [0.0, 100*NNS_BasicActiveSensor.maxAllowableThreeSigAngDevPercent];
                app.maxAngErrSlider.Value = 100*sensor.threeSigAngDevPercent;
                app.maxAngErrSlider.MajorTicks = [0.0 : 20*NNS_BasicActiveSensor.maxAllowableThreeSigAngDevPercent : 100*NNS_BasicActiveSensor.maxAllowableThreeSigAngDevPercent]; 
                app.maxAngErrSlider.MinorTicks = [0.0 : 05*NNS_BasicActiveSensor.maxAllowableThreeSigAngDevPercent : 100*NNS_BasicActiveSensor.maxAllowableThreeSigAngDevPercent]; 
%                 [t, fH] = createDecimalSlider(handles.maxAngErrSlider.Parent, 1E-3, handles.maxAngErrSlider.Position, 0.0, 100*NNS_BasicActiveSensor.maxAllowableThreeSigAngDevPercent, 100*sensor.threeSigAngDevPercent, 20*NNS_BasicActiveSensor.maxAllowableThreeSigAngDevPercent, 5*NNS_BasicActiveSensor.maxAllowableThreeSigAngDevPercent);
%                 delete(handles.maxAngErrSlider);
%                 handles.maxAngErrSlider = t;
%                 t.JavaComponent.StateChangedCallback = @(app,eventData) maxAngErrSlider_Callback(app,eventData,handles);
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function basicActiveSensorEditorGUI_OpeningFcn(app, varargin)
            % Ensure that the app appears on screen when run
            movegui(app.basicActiveSensorEditorGUI, 'onscreen');
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to basicActiveSensorEditorGUI (see VARARGIN)
        
            % Choose default command line output for basicActiveSensorEditorGUI
            handles.output = hObject;

            movegui(app.basicActiveSensorEditorGUI,"center");
        
            sensor = varargin{1};
            setappdata(hObject,'sensor',sensor);
        
            %set up GUI
            handles = setupGUI(app, sensor, handles);
        
            % Update handles structure
            guidata(hObject, handles);
        end

        % Callback function: maxAngErrSlider, maxAngErrSlider
        function maxAngErrSlider_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to maxAngErrSlider (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'Value') returns position of slider
            %        get(hObject,'Min') and get(hObject,'Max') to determine range of slider
            sensor = getappdata(handles.basicActiveSensorEditorGUI,'sensor');
%             cb(hObject,eventdata);
            sensor.threeSigAngDevPercent = app.maxAngErrSlider.Value/100;
        end

        % Callback function: maxConeAngleSlider, maxConeAngleSlider
        function maxConeAngleSlider_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to maxConeAngleSlider (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'Value') returns position of slider
            %        get(hObject,'Min') and get(hObject,'Max') to determine range of slider
            sensor = getappdata(handles.basicActiveSensorEditorGUI,'sensor');
%             cb(hObject,eventdata);
            sensor.maxConeAngle = deg2rad(app.maxConeAngleSlider.Value);
        end

        % Callback function: maxRangeErrSlider, maxRangeErrSlider
        function maxRangeErrSlider_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to maxRangeErrSlider (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'Value') returns position of slider
            %        get(hObject,'Min') and get(hObject,'Max') to determine range of slider
            sensor = getappdata(handles.basicActiveSensorEditorGUI,'sensor');
%             cb(hObject,eventdata);
            sensor.threeSigRngDevPercent = app.maxRangeErrSlider.Value/100;
        end

        % Callback function: sensorRangeSlider, sensorRangeSlider
        function sensorRangeSlider_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to sensorRangeSlider (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'Value') returns position of slider
            %        get(hObject,'Min') and get(hObject,'Max') to determine range of slider
            sensor = getappdata(handles.basicActiveSensorEditorGUI,'sensor');
%             cb(hObject,eventdata);
            sensor.maxRng = app.sensorRangeSlider.Value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.basicActiveSensorEditorGUI = uifigure('Visible', 'off');
            app.basicActiveSensorEditorGUI.Name = 'Edit Active Radar';
            app.basicActiveSensorEditorGUI.Tag = 'basicActiveSensorEditorGUI';
            app.GridLayout = uigridlayout(app.basicActiveSensorEditorGUI);
            app.text2 = uilabel(app.GridLayout);
            app.text2.Tag = 'text2';
            app.text2.Text = 'Edit Active Radar';
            app.uipanel1 = uipanel(app.GridLayout);
            app.uipanel1.Title = 'Editable Parameters';
            app.uipanel1.Tag = 'uipanel1';
            app.GridLayout2 = uigridlayout(app.uipanel1);
            app.text3 = uilabel(app.GridLayout2);
            app.text3.Tag = 'text3';
            app.text3.Text = 'Sensor Range (m)';
            app.sensorRangeSlider = uislider(app.GridLayout2);
            app.sensorRangeSlider.Limits = [0 1];
            app.sensorRangeSlider.MajorTicks = [];
            app.sensorRangeSlider.ValueChangedFcn = createCallbackFcn(app, @sensorRangeSlider_Callback, true);
            app.sensorRangeSlider.ValueChangingFcn = createCallbackFcn(app, @sensorRangeSlider_Callback, true);
            app.sensorRangeSlider.MinorTicks = [];
            app.sensorRangeSlider.Tag = 'sensorRangeSlider';
            app.text4 = uilabel(app.GridLayout2);
            app.text4.Tag = 'text4';
            app.text4.Text = 'Maxiumum Cone Angle (deg)';
            app.maxConeAngleSlider = uislider(app.GridLayout2);
            app.maxConeAngleSlider.Limits = [0 1];
            app.maxConeAngleSlider.MajorTicks = [];
            app.maxConeAngleSlider.ValueChangedFcn = createCallbackFcn(app, @maxConeAngleSlider_Callback, true);
            app.maxConeAngleSlider.ValueChangingFcn = createCallbackFcn(app, @maxConeAngleSlider_Callback, true);
            app.maxConeAngleSlider.MinorTicks = [];
            app.maxConeAngleSlider.Tag = 'maxConeAngleSlider';
            app.text6 = uilabel(app.GridLayout2);
            app.text6.Tag = 'text6';
            app.text6.Text = 'Maximum Range Error (%)';
            app.maxRangeErrSlider = uislider(app.GridLayout2);
            app.maxRangeErrSlider.Limits = [0 1];
            app.maxRangeErrSlider.MajorTicks = [];
            app.maxRangeErrSlider.ValueChangedFcn = createCallbackFcn(app, @maxRangeErrSlider_Callback, true);
            app.maxRangeErrSlider.ValueChangingFcn = createCallbackFcn(app, @maxRangeErrSlider_Callback, true);
            app.maxRangeErrSlider.MinorTicks = [];
            app.maxRangeErrSlider.Tag = 'maxRangeErrSlider';
            app.text5 = uilabel(app.GridLayout2);
            app.text5.Tag = 'text5';
            app.text5.Text = 'Maximum Angular Error (%)';
            app.maxAngErrSlider = uislider(app.GridLayout2);
            app.maxAngErrSlider.Limits = [0 1];
            app.maxAngErrSlider.MajorTicks = [];
            app.maxAngErrSlider.ValueChangedFcn = createCallbackFcn(app, @maxAngErrSlider_Callback, true);
            app.maxAngErrSlider.ValueChangingFcn = createCallbackFcn(app, @maxAngErrSlider_Callback, true);
            app.maxAngErrSlider.MinorTicks = [];
            app.maxAngErrSlider.Tag = 'maxAngErrSlider';
            % Show the figure after all components are created
            app.basicActiveSensorEditorGUI.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = basicActiveSensorEditorGUI_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.basicActiveSensorEditorGUI)

                % Execute the startup function
                runStartupFcn(app, @(app)basicActiveSensorEditorGUI_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.basicActiveSensorEditorGUI)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.basicActiveSensorEditorGUI)
        end
    end
end