classdef basicHullEngineRudderEditorGUI_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        basicHullEngineRudderEditorGUI  matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        uipanel1                        matlab.ui.container.Panel
        GridLayout2                     matlab.ui.container.GridLayout
        torqueSlider                    matlab.ui.control.Slider
        text7                           matlab.ui.control.Label
        thrustSlider                    matlab.ui.control.Slider
        text6                           matlab.ui.control.Label
        thicknessSlider                 matlab.ui.control.Slider
        text8                           matlab.ui.control.Label
        text5                           matlab.ui.control.Label
    end

    
    methods (Access = private)
        function handles = setupGUI(app, ship, handles)
                hull = ship.hull;
                engine = ship.components.engComps(1);
                rudder = ship.components.rudComps(1);
            
                %Set Up Scroll Bars
                app.thrustSlider.Limits = [0.0, NNS_BasicEngine.maximumAllowedMaxThrust];
                app.thrustSlider.Value = engine.maxThrust;
                app.thrustSlider.MajorTicks = [0.0 : engine.maximumAllowedMaxThrust/5 : NNS_BasicEngine.maximumAllowedMaxThrust];
                app.thrustSlider.MinorTicks = [0.0 : engine.maximumAllowedMaxThrust/20 : NNS_BasicEngine.maximumAllowedMaxThrust];
%                 [t, fH] = createDecimalSlider(handles.thrustSlider.Parent, 1E-3, handles.thrustSlider.Position, 0.0, NNS_BasicEngine.maximumAllowedMaxThrust, engine.maxThrust, NNS_BasicEngine.maximumAllowedMaxThrust/5, NNS_BasicEngine.maximumAllowedMaxThrust/20);
%                 delete(handles.thrustSlider);
%                 handles.thrustSlider = t;
%                 t.JavaComponent.StateChangedCallback = @(hSrc,eventData) thrustSlider_Callback(hSrc,eventData,handles,fH);
            
                app.torqueSlider.Limits = [0.0, NNS_BasicRudder.maximumAllowedMaxTorque];
                app.torqueSlider.Value = rudder.maxTorque;
                app.torqueSlider.MajorTicks = [0.0 : rudder.maximumAllowedMaxTorque/5 : NNS_BasicEngine.maximumAllowedMaxThrust];
                app.torqueSlider.MinorTicks = [0.0 : rudder.maximumAllowedMaxTorque/20 : NNS_BasicEngine.maximumAllowedMaxThrust];
%                 [t, fH] = createDecimalSlider(handles.torqueSlider.Parent, 1E-3, handles.torqueSlider.Position, 0.0, NNS_BasicRudder.maximumAllowedMaxTorque, rudder.maxTorque, NNS_BasicRudder.maximumAllowedMaxTorque/5, NNS_BasicRudder.maximumAllowedMaxTorque/20);
%                 delete(handles.torqueSlider);
%                 handles.torqueSlider = t;
%                 t.JavaComponent.StateChangedCallback = @(hSrc,eventData) torqueSlider_Callback(hSrc,eventData,handles,fH);
            
                app.thicknessSlider.Limits = [NNS_BasicShipHull.minHullThickness, NNS_BasicShipHull.maxHullThickness];
                app.thicknessSlider.Value = hull.hullThickness;
                app.thicknessSlider.MajorTicks = [0.0 : NNS_BasicShipHull.maxHullThickness/5 : NNS_BasicEngine.maximumAllowedMaxThrust];
                app.thicknessSlider.MinorTicks = [0.0 : NNS_BasicShipHull.maxHullThickness/20 : NNS_BasicEngine.maximumAllowedMaxThrust];
%                 [t, fH] = createDecimalSlider(handles.thicknessSlider.Parent, 1E-6, handles.thicknessSlider.Position, NNS_BasicShipHull.minHullThickness, NNS_BasicShipHull.maxHullThickness, hull.hullThickness, NNS_BasicShipHull.maxHullThickness/5, NNS_BasicShipHull.maxHullThickness/20);
%                 delete(handles.thicknessSlider);
%                 handles.thicknessSlider = t;
%                 t.JavaComponent.StateChangedCallback = @(hSrc,eventData) thicknessSlider_Callback(hSrc,eventData,handles,fH);
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function basicHullEngineRudderEditorGUI_OpeningFcn(app, varargin)
            % Ensure that the app appears on screen when run
            movegui(app.basicHullEngineRudderEditorGUI, 'onscreen');
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to basicHullEngineRudderEditorGUI (see VARARGIN)
            
                % Choose default command line output for basicHullEngineRudderEditorGUI
                handles.output = hObject;

                movegui(app.basicHullEngineRudderEditorGUI, "center");
            
                ship = varargin{1};
                setappdata(hObject,'ship',ship);
            
                %set up GUI
                handles = setupGUI(app, ship, handles);
            
                % Update handles structure
                guidata(hObject, handles);
        end

        % Callback function: thicknessSlider, thicknessSlider
        function thicknessSlider_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to thicknessSlider (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'Value') returns position of slider
            %        get(hObject,'Min') and get(hObject,'Max') to determine range of slider
            ship = getappdata(handles.basicHullEngineRudderEditorGUI,'ship');
            hull = ship.hull;
%                 cb(hObject,eventdata);
            hull.hullThickness = app.thicknessSlider.Value;
        end

        % Callback function: thrustSlider, thrustSlider
        function thrustSlider_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to thrustSlider (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'Value') returns position of slider
            %        get(hObject,'Min') and get(hObject,'Max') to determine range of slider
            ship = getappdata(handles.basicHullEngineRudderEditorGUI,'ship');
            engine = ship.components.engComps(1);
%                 cb(hObject,eventdata);
            engine.maxThrust = app.thrustSlider.Value;
        end

        % Callback function: torqueSlider, torqueSlider
        function torqueSlider_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to torqueSlider (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'Value') returns position of slider
            %        get(hObject,'Min') and get(hObject,'Max') to determine range of slider
            ship = getappdata(handles.basicHullEngineRudderEditorGUI,'ship');
            rudder = ship.components.rudComps(1);
%                 cb(hObject,eventdata);
            rudder.maxTorque = app.torqueSlider.Value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.basicHullEngineRudderEditorGUI = uifigure('Visible', 'off');
            app.basicHullEngineRudderEditorGUI.Name = 'Edit Ship Hull';
            app.basicHullEngineRudderEditorGUI.Tag = 'basicHullEngineRudderEditorGUI';
            app.GridLayout = uigridlayout(app.basicHullEngineRudderEditorGUI);
            app.text5 = uilabel(app.GridLayout);
            app.text5.Tag = 'text5';
            app.text5.Text = 'Edit Hull';
            app.uipanel1 = uipanel(app.GridLayout);
            app.uipanel1.Title = 'Editable Parameters';
            app.uipanel1.Tag = 'uipanel1';
            app.GridLayout2 = uigridlayout(app.uipanel1);
            app.text8 = uilabel(app.GridLayout2);
            app.text8.Tag = 'text8';
            app.text8.Text = 'Hull Thickness (mm)';
            app.thicknessSlider = uislider(app.GridLayout2);
            app.thicknessSlider.Limits = [0 1];
            app.thicknessSlider.MajorTicks = [];
            app.thicknessSlider.ValueChangedFcn = createCallbackFcn(app, @thicknessSlider_Callback, true);
            app.thicknessSlider.ValueChangingFcn = createCallbackFcn(app, @thicknessSlider_Callback, true);
            app.thicknessSlider.MinorTicks = [];
            app.thicknessSlider.Tag = 'thicknessSlider';
            app.text6 = uilabel(app.GridLayout2);
            app.text6.Tag = 'text6';
            app.text6.Text = 'Maximum Engine Thrust (N)';
            app.thrustSlider = uislider(app.GridLayout2);
            app.thrustSlider.Limits = [0 1];
            app.thrustSlider.MajorTicks = [];
            app.thrustSlider.ValueChangedFcn = createCallbackFcn(app, @thrustSlider_Callback, true);
            app.thrustSlider.ValueChangingFcn = createCallbackFcn(app, @thrustSlider_Callback, true);
            app.thrustSlider.MinorTicks = [];
            app.thrustSlider.Tag = 'thrustSlider';
            app.text7 = uilabel(app.GridLayout2);
            app.text7.Tag = 'text7';
            app.text7.Text = 'Maximum Rudder Torque (N-m)';
            app.torqueSlider = uislider(app.GridLayout2);
            app.torqueSlider.Limits = [0 1];
            app.torqueSlider.MajorTicks = [];
            app.torqueSlider.ValueChangedFcn = createCallbackFcn(app, @torqueSlider_Callback, true);
            app.torqueSlider.ValueChangingFcn = createCallbackFcn(app, @torqueSlider_Callback, true);
            app.torqueSlider.MinorTicks = [];
            app.torqueSlider.Tag = 'torqueSlider';
            % Show the figure after all components are created
            app.basicHullEngineRudderEditorGUI.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = basicHullEngineRudderEditorGUI_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.basicHullEngineRudderEditorGUI)

                % Execute the startup function
                runStartupFcn(app, @(app)basicHullEngineRudderEditorGUI_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.basicHullEngineRudderEditorGUI)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.basicHullEngineRudderEditorGUI)
        end
    end
end