classdef basicMineLayerEditorGUI_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        basicMineLayerEditorGUI  matlab.ui.Figure
        GridLayout               matlab.ui.container.GridLayout
        uipanel1                 matlab.ui.container.Panel
        GridLayout2              matlab.ui.container.GridLayout
        reloadTimeSlider         matlab.ui.control.Slider
        text7                    matlab.ui.control.Label
        baseDamageSlider         matlab.ui.control.Slider
        text4                    matlab.ui.control.Label
        text2                    matlab.ui.control.Label
    end

    
    methods (Access = private)
        function handles = setupGUI(app, mineLayer, handles)
                %Set Up Scroll Bars
                app.baseDamageSlider.Limits = [NNS_BasicMineLayer.minBaseDamage, NNS_BasicMineLayer.maxBaseDamage];
                app.baseDamageSlider.Value = mineLayer.baseDamage;
                app.baseDamageSlider.MajorTicks = [NNS_BasicMineLayer.minBaseDamage : NNS_BasicMineLayer.maxBaseDamage/5 : NNS_BasicMineLayer.maxBaseDamage]; %#ok<*NBRAK2> 
                app.baseDamageSlider.MinorTicks = [NNS_BasicMineLayer.minBaseDamage : NNS_BasicMineLayer.maxBaseDamage/20 : NNS_BasicMineLayer.maxBaseDamage];
%                 [t, fH] = createDecimalSlider(handles.baseDamageSlider.Parent, 1E-3, handles.baseDamageSlider.Position, NNS_BasicMineLayer.minBaseDamage, ...
%                                               NNS_BasicMineLayer.maxBaseDamage, mineLayer.baseDamage, NNS_BasicMineLayer.maxBaseDamage/5, NNS_BasicMineLayer.maxBaseDamage/20);
%                 delete(handles.baseDamageSlider);
%                 handles.baseDamageSlider = t;
%                 t.JavaComponent.StateChangedCallback = @(hSrc,eventData) baseDamageSlider_Callback(hSrc,eventData,handles,fH);
            
                app.reloadTimeSlider.Limits = [NNS_BasicMineLayer.minReloadTime, NNS_BasicMineLayer.maxReloadTime];
                app.reloadTimeSlider.Value = mineLayer.reloadTime;
                app.reloadTimeSlider.MajorTicks = [NNS_BasicMineLayer.minReloadTime : NNS_BasicMineLayer.maxReloadTime/5 : NNS_BasicMineLayer.maxReloadTime];
                app.reloadTimeSlider.MinorTicks = [NNS_BasicMineLayer.minReloadTime : NNS_BasicMineLayer.maxReloadTime/20 : NNS_BasicMineLayer.maxReloadTime];
%                 [t, fH] = createDecimalSlider(handles.reloadTimeSlider.Parent, 1E-3, handles.reloadTimeSlider.Position, NNS_BasicMineLayer.minReloadTime, ...
%                                               NNS_BasicMineLayer.maxReloadTime, mineLayer.reloadTime, NNS_BasicMineLayer.maxReloadTime/5, NNS_BasicMineLayer.maxReloadTime/20);
%                 delete(handles.reloadTimeSlider);
%                 handles.reloadTimeSlider = t;
%             	t.JavaComponent.StateChangedCallback = @(hSrc,eventData) reloadTimeSlider_Callback(hSrc,eventData,handles,fH);
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function basicMineLayerEditorGUI_OpeningFcn(app, varargin)
            % Ensure that the app appears on screen when run
            movegui(app.basicMineLayerEditorGUI, 'onscreen');
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to basicMineLayerEditorGUI (see VARARGIN)
            
                % Choose default command line output for basicMineLayerEditorGUI
                handles.output = hObject;

                movegui(app.basicMineLayerEditorGUI, "center");
            
                mineLayer = varargin{1};
                setappdata(hObject,'mineLayer',mineLayer);
            
                %set up GUI
                handles = setupGUI(app, mineLayer, handles);
            
                % Update handles structure
                guidata(hObject, handles);
        end

        % Callback function: baseDamageSlider, baseDamageSlider
        function baseDamageSlider_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to baseDamageSlider (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'Value') returns position of slider
            %        get(hObject,'Min') and get(hObject,'Max') to determine range of slider
                mineLayer = getappdata(handles.basicMineLayerEditorGUI,'mineLayer');
%                 cb(hObject,eventdata);
                mineLayer.baseDamage = app.baseDamageSlider.Value;
        end

        % Callback function: reloadTimeSlider, reloadTimeSlider
        function reloadTimeSlider_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to reloadTimeSlider (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'Value') returns position of slider
            %        get(hObject,'Min') and get(hObject,'Max') to determine range of slider
                mineLayer = getappdata(handles.basicMineLayerEditorGUI,'mineLayer');
%                 cb(hObject,eventdata);
                mineLayer.reloadTime = app.reloadTimeSlider.Value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.basicMineLayerEditorGUI = uifigure('Visible', 'off');
            app.basicMineLayerEditorGUI.Name = 'Edit Mine Layer';
            app.basicMineLayerEditorGUI.Tag = 'basicMineLayerEditorGUI';
            app.GridLayout = uigridlayout(app.basicMineLayerEditorGUI);
            app.text2 = uilabel(app.GridLayout);
            app.text2.Tag = 'text2';
            app.text2.Text = 'Edit Mine Layer';
            app.uipanel1 = uipanel(app.GridLayout);
            app.uipanel1.Title = 'Editable Parameters';
            app.uipanel1.Tag = 'uipanel1';
            app.GridLayout2 = uigridlayout(app.uipanel1);
            app.text4 = uilabel(app.GridLayout2);
            app.text4.Tag = 'text4';
            app.text4.Text = 'Base Damage';
            app.baseDamageSlider = uislider(app.GridLayout2);
            app.baseDamageSlider.Limits = [0 1];
            app.baseDamageSlider.MajorTicks = [];
            app.baseDamageSlider.ValueChangedFcn = createCallbackFcn(app, @baseDamageSlider_Callback, true);
            app.baseDamageSlider.ValueChangingFcn = createCallbackFcn(app, @baseDamageSlider_Callback, true);
            app.baseDamageSlider.MinorTicks = [];
            app.baseDamageSlider.Tag = 'baseDamageSlider';
            app.text7 = uilabel(app.GridLayout2);
            app.text7.Tag = 'text7';
            app.text7.Text = 'Reload Time (sec)';
            app.reloadTimeSlider = uislider(app.GridLayout2);
            app.reloadTimeSlider.Limits = [0 1];
            app.reloadTimeSlider.MajorTicks = [];
            app.reloadTimeSlider.ValueChangedFcn = createCallbackFcn(app, @reloadTimeSlider_Callback, true);
            app.reloadTimeSlider.ValueChangingFcn = createCallbackFcn(app, @reloadTimeSlider_Callback, true);
            app.reloadTimeSlider.MinorTicks = [];
            app.reloadTimeSlider.Tag = 'reloadTimeSlider';
            % Show the figure after all components are created
            app.basicMineLayerEditorGUI.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = basicMineLayerEditorGUI_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.basicMineLayerEditorGUI)

                % Execute the startup function
                runStartupFcn(app, @(app)basicMineLayerEditorGUI_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.basicMineLayerEditorGUI)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.basicMineLayerEditorGUI)
        end
    end
end