classdef basicPowerGeneratorEditorGUI_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        basicPowerGeneratorEditorGUI  matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        uipanel1                      matlab.ui.container.Panel
        GridLayout2                   matlab.ui.container.GridLayout
        powerGenSlider                matlab.ui.control.Slider
        text3                         matlab.ui.control.Label
        text2                         matlab.ui.control.Label
    end

    
    methods (Access = private)
        function handles = setupGUI(app, powerGen, handles)
                %Set Up Scroll Bars
                app.powerGenSlider.Limits = [0.0, NNS_BasicPowerGenerator.maximumAllowedMaxPowerGen];
                app.powerGenSlider.Value = powerGen.maxPowerGen;
                app.powerGenSlider.MajorTicks = [0.0 : NNS_BasicPowerGenerator.maximumAllowedMaxPowerGen/5 : NNS_BasicPowerGenerator.maximumAllowedMaxPowerGen]; %#ok<*NBRAK2> 
                app.powerGenSlider.MinorTicks = [0.0 : NNS_BasicPowerGenerator.maximumAllowedMaxPowerGen/20 : NNS_BasicPowerGenerator.maximumAllowedMaxPowerGen];
%                 [t, fH] = createDecimalSlider(handles.powerGenSlider.Parent, 1E-3, handles.powerGenSlider.Position, 0.0, NNS_BasicPowerGenerator.maximumAllowedMaxPowerGen, powerGen.maxPowerGen, NNS_BasicPowerGenerator.maximumAllowedMaxPowerGen/5, NNS_BasicPowerGenerator.maximumAllowedMaxPowerGen/20);
%                 delete(handles.powerGenSlider);
%                 handles.powerGenSlider = t;
%                 t.JavaComponent.StateChangedCallback = @(hSrc,eventData) powerGenSlider_Callback(hSrc,eventData,handles, fH);
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function basicPowerGeneratorEditorGUI_OpeningFcn(app, varargin)
            % Ensure that the app appears on screen when run
            movegui(app.basicPowerGeneratorEditorGUI, 'onscreen');
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to basicPowerGeneratorEditorGUI (see VARARGIN)
            
                % Choose default command line output for basicPowerGeneratorEditorGUI
                handles.output = hObject;

                movegui(app.basicPowerGeneratorEditorGUI, "center");
            
                powerGen = varargin{1};
                setappdata(hObject,'powerGen',powerGen);
            
                %set up GUI
                handles = setupGUI(app, powerGen, handles);
            
                % Update handles structure
                guidata(hObject, handles);
        end

        % Value changed function: powerGenSlider
        function powerGenSlider_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to powerGenSlider (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'Value') returns position of slider
            %        get(hObject,'Min') and get(hObject,'Max') to determine range of slider
                powerGen = getappdata(handles.basicPowerGeneratorEditorGUI,'powerGen');
%                 cb(hObject,eventdata);
                powerGen.maxPowerGen = app.powerGenSlider.Value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.basicPowerGeneratorEditorGUI = uifigure('Visible', 'off');
            app.basicPowerGeneratorEditorGUI.Name = 'Edit Power Generator';
            app.basicPowerGeneratorEditorGUI.Tag = 'basicPowerGeneratorEditorGUI';
            app.GridLayout = uigridlayout(app.basicPowerGeneratorEditorGUI);
            app.text2 = uilabel(app.GridLayout);
            app.text2.Tag = 'text2';
            app.text2.Text = 'Edit Power Generator';
            app.uipanel1 = uipanel(app.GridLayout);
            app.uipanel1.Title = 'Editable Parameters';
            app.uipanel1.Tag = 'uipanel1';
            app.GridLayout2 = uigridlayout(app.uipanel1);
            app.text3 = uilabel(app.GridLayout2);
            app.text3.Tag = 'text3';
            app.text3.Text = 'Power Producted (W)';
            app.powerGenSlider = uislider(app.GridLayout2);
            app.powerGenSlider.Limits = [0 1];
            app.powerGenSlider.MajorTicks = [];
            app.powerGenSlider.ValueChangedFcn = createCallbackFcn(app, @powerGenSlider_Callback, true);
            app.powerGenSlider.MinorTicks = [];
            app.powerGenSlider.Tag = 'powerGenSlider';
            % Show the figure after all components are created
            app.basicPowerGeneratorEditorGUI.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = basicPowerGeneratorEditorGUI_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.basicPowerGeneratorEditorGUI)

                % Execute the startup function
                runStartupFcn(app, @(app)basicPowerGeneratorEditorGUI_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.basicPowerGeneratorEditorGUI)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.basicPowerGeneratorEditorGUI)
        end
    end
end