classdef basicTurretedGunEditorGUI_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        basicTurretedGunEditorGUI  matlab.ui.Figure
        GridLayout                 matlab.ui.container.GridLayout
        uipanel1                   matlab.ui.container.Panel
        GridLayout2                matlab.ui.container.GridLayout
        maxAngErrorSlider          matlab.ui.control.Slider
        text5                      matlab.ui.control.Label
        reloadTimeSlider           matlab.ui.control.Slider
        text7                      matlab.ui.control.Label
        baseDamageSlider           matlab.ui.control.Slider
        text4                      matlab.ui.control.Label
        muzzleVelSlider            matlab.ui.control.Slider
        text3                      matlab.ui.control.Label
        text2                      matlab.ui.control.Label
    end

    
    methods (Access = private)
        function handles = setupGUI(app, gun, handles)
                %Set Up Scroll Bars
                app.muzzleVelSlider.Limits = [NNS_BasicTurretedGun.minMuzzleVelocity, NNS_BasicTurretedGun.maxMuzzleVelocity];
                app.muzzleVelSlider.Value = gun.roundSpeed;
                app.muzzleVelSlider.MajorTicks = [NNS_BasicTurretedGun.minMuzzleVelocity : NNS_BasicTurretedGun.maxMuzzleVelocity/5 : NNS_BasicTurretedGun.maxMuzzleVelocity]; %#ok<*NBRAK2> 
                app.muzzleVelSlider.MinorTicks = [NNS_BasicTurretedGun.minMuzzleVelocity : NNS_BasicTurretedGun.maxMuzzleVelocity/20 : NNS_BasicTurretedGun.maxMuzzleVelocity];
%                 [t, fH] = createDecimalSlider(handles.muzzleVelSlider.Parent, 1E-3, handles.muzzleVelSlider.Position, NNS_BasicTurretedGun.minMuzzleVelocity, ...
%                                               NNS_BasicTurretedGun.maxMuzzleVelocity, gun.roundSpeed, NNS_BasicTurretedGun.maxMuzzleVelocity/5, NNS_BasicTurretedGun.maxMuzzleVelocity/20);
%                 delete(handles.muzzleVelSlider);
%                 handles.muzzleVelSlider = t;
%                 t.JavaComponent.StateChangedCallback = @(hSrc,eventData) muzzleVelSlider_Callback(hSrc,eventData,handles,fH);

                app.baseDamageSlider.Limits = [NNS_BasicTurretedGun.minBaseDamage, NNS_BasicTurretedGun.maxBaseDamage];
                app.baseDamageSlider.Value = gun.baseDamage;
                app.baseDamageSlider.MajorTicks = [NNS_BasicTurretedGun.minBaseDamage : NNS_BasicTurretedGun.maxBaseDamage/5 : NNS_BasicTurretedGun.maxBaseDamage]; %#ok<*NBRAK2> 
                app.baseDamageSlider.MinorTicks = [NNS_BasicTurretedGun.minBaseDamage : NNS_BasicTurretedGun.maxBaseDamage/20 : NNS_BasicTurretedGun.maxBaseDamage];
%                 [t, fH] = createDecimalSlider(handles.baseDamageSlider.Parent, 1E-3, handles.baseDamageSlider.Position, NNS_BasicTurretedGun.minBaseDamage, ...
%                                               NNS_BasicTurretedGun.maxBaseDamage, gun.baseDamage, NNS_BasicTurretedGun.maxBaseDamage/5, NNS_BasicTurretedGun.maxBaseDamage/20);
%                 delete(handles.baseDamageSlider);
%                 handles.baseDamageSlider = t;
%                 t.JavaComponent.StateChangedCallback = @(hSrc,eventData) baseDamageSlider_Callback(hSrc,eventData,handles,fH);
  
                app.reloadTimeSlider.Limits = [NNS_BasicTurretedGun.minReloadTime, NNS_BasicTurretedGun.maxReloadTime];
                app.reloadTimeSlider.Value = gun.reloadTime;
                app.reloadTimeSlider.MajorTicks = [NNS_BasicTurretedGun.minReloadTime : NNS_BasicTurretedGun.maxReloadTime/5 : NNS_BasicTurretedGun.maxReloadTime]; %#ok<*NBRAK2> 
                app.reloadTimeSlider.MinorTicks = [NNS_BasicTurretedGun.minReloadTime : NNS_BasicTurretedGun.maxReloadTime/20 : NNS_BasicTurretedGun.maxReloadTime];
%                 [t, fH] = createDecimalSlider(handles.reloadTimeSlider.Parent, 1E-3, handles.reloadTimeSlider.Position, NNS_BasicTurretedGun.minReloadTime, ...
%                                               NNS_BasicTurretedGun.maxReloadTime, gun.reloadTime, NNS_BasicTurretedGun.maxReloadTime/5, NNS_BasicTurretedGun.maxReloadTime/20);
%                 delete(handles.reloadTimeSlider);
%                 handles.reloadTimeSlider = t;
%             	t.JavaComponent.StateChangedCallback = @(hSrc,eventData) reloadTimeSlider_Callback(hSrc,eventData,handles,fH);

                app.maxAngErrorSlider.Limits = [0.0, NNS_BasicTurretedGun.maxMaxAngularError];
                app.maxAngErrorSlider.Value = rad2deg(gun.angleError);
                app.maxAngErrorSlider.MajorTicks = [0.0 : NNS_BasicTurretedGun.maxMaxAngularError/5 : NNS_BasicTurretedGun.maxMaxAngularError]; %#ok<*NBRAK2> 
                app.maxAngErrorSlider.MinorTicks = [0.0 : NNS_BasicTurretedGun.maxMaxAngularError/20 : NNS_BasicTurretedGun.maxMaxAngularError];
%                 [t, fH] = createDecimalSlider(handles.maxAngErrorSlider.Parent, 1E-3, handles.maxAngErrorSlider.Position, 0.0, ...
%                                               NNS_BasicTurretedGun.maxMaxAngularError, rad2deg(gun.angleError), NNS_BasicTurretedGun.maxMaxAngularError/5, NNS_BasicTurretedGun.maxMaxAngularError/20);
%                 delete(handles.maxAngErrorSlider);
%                 handles.maxAngErrorSlider = t;
%                 t.JavaComponent.StateChangedCallback = @(hSrc,eventData) maxAngErrorSlider_Callback(hSrc,eventData,handles,fH);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function basicTurretedGunEditorGUI_OpeningFcn(app, varargin)
            % Ensure that the app appears on screen when run
            movegui(app.basicTurretedGunEditorGUI, 'onscreen');
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to basicTurretedGunEditorGUI (see VARARGIN)
            
                % Choose default command line output for basicTurretedGunEditorGUI
                handles.output = hObject;

                movegui(app.basicTurretedGunEditorGUI, "center");
            
                gun = varargin{1};
                setappdata(hObject,'gun',gun);
            
                %set up GUI
                handles = setupGUI(app, gun, handles);
            
                % Update handles structure
                guidata(hObject, handles);
        end

        % Callback function: baseDamageSlider, baseDamageSlider
        function baseDamageSlider_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to baseDamageSlider (see GCBO)
            % eventdata  reserved - to be defined i a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'Value') returns position of slider
            %        get(hObject,'Min') and get(hObject,'Max') to determine range of slider
                gun = getappdata(handles.basicTurretedGunEditorGUI,'gun');
%                 cb(hObject,eventdata);
                gun.baseDamage = app.baseDamageSlider.Value;
        end

        % Callback function: maxAngErrorSlider, maxAngErrorSlider
        function maxAngErrorSlider_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to maxAngErrorSlider (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'Value') returns position of slider
            %        get(hObject,'Min') and get(hObject,'Max') to determine range of slider
                gun = getappdata(handles.basicTurretedGunEditorGUI,'gun');
%                 cb(hObject,eventdata);
                gun.angleError = deg2rad(app.maxAngErrorSlider.Value);
        end

        % Callback function: muzzleVelSlider, muzzleVelSlider
        function muzzleVelSlider_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to muzzleVelSlider (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'Value') returns position of slider
            %        get(hObject,'Min') and get(hObject,'Max') to determine range of slider
                gun = getappdata(handles.basicTurretedGunEditorGUI,'gun');
%                 cb(hObject,eventdata);
                gun.roundSpeed = app.muzzleVelSlider.Value;
        end

        % Callback function: reloadTimeSlider, reloadTimeSlider
        function reloadTimeSlider_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to reloadTimeSlider (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'Value') returns position of slider
            %        get(hObject,'Min') and get(hObject,'Max') to determine range of slider
                gun = getappdata(handles.basicTurretedGunEditorGUI,'gun');
%                 cb(hObject,eventdata);
                gun.reloadTime = app.reloadTimeSlider.Value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.basicTurretedGunEditorGUI = uifigure('Visible', 'off');
            app.basicTurretedGunEditorGUI.Name = 'Edit Turreted Gun';
            app.basicTurretedGunEditorGUI.Tag = 'basicTurretedGunEditorGUI';
            app.GridLayout = uigridlayout(app.basicTurretedGunEditorGUI);
            app.text2 = uilabel(app.GridLayout);
            app.text2.Tag = 'text2';
            app.text2.Text = 'Edit Turreted Gun';
            app.uipanel1 = uipanel(app.GridLayout);
            app.uipanel1.Title = 'Editable Parameters';
            app.uipanel1.Tag = 'uipanel1';
            app.GridLayout2 = uigridlayout(app.uipanel1);
            app.text3 = uilabel(app.GridLayout2);
            app.text3.Tag = 'text3';
            app.text3.Text = 'Muzzle Velocity (m/s)';
            app.muzzleVelSlider = uislider(app.GridLayout2);
            app.muzzleVelSlider.Limits = [0 1];
            app.muzzleVelSlider.MajorTicks = [];
            app.muzzleVelSlider.ValueChangedFcn = createCallbackFcn(app, @muzzleVelSlider_Callback, true);
            app.muzzleVelSlider.ValueChangingFcn = createCallbackFcn(app, @muzzleVelSlider_Callback, true);
            app.muzzleVelSlider.MinorTicks = [];
            app.muzzleVelSlider.Tag = 'muzzleVelSlider';
            app.text4 = uilabel(app.GridLayout2);
            app.text4.Tag = 'text4';
            app.text4.Text = 'Base Damage';
            app.baseDamageSlider = uislider(app.GridLayout2);
            app.baseDamageSlider.Limits = [0 1];
            app.baseDamageSlider.MajorTicks = [];
            app.baseDamageSlider.ValueChangedFcn = createCallbackFcn(app, @baseDamageSlider_Callback, true);
            app.baseDamageSlider.ValueChangingFcn = createCallbackFcn(app, @baseDamageSlider_Callback, true);
            app.baseDamageSlider.MinorTicks = [];
            app.baseDamageSlider.Tag = 'baseDamageSlider';
            app.text7 = uilabel(app.GridLayout2);
            app.text7.Tag = 'text7';
            app.text7.Text = 'Reload Time (sec)';
            app.reloadTimeSlider = uislider(app.GridLayout2);
            app.reloadTimeSlider.Limits = [0 1];
            app.reloadTimeSlider.MajorTicks = [];
            app.reloadTimeSlider.ValueChangedFcn = createCallbackFcn(app, @reloadTimeSlider_Callback, true);
            app.reloadTimeSlider.ValueChangingFcn = createCallbackFcn(app, @reloadTimeSlider_Callback, true);
            app.reloadTimeSlider.MinorTicks = [];
            app.reloadTimeSlider.Tag = 'reloadTimeSlider';
            app.text5 = uilabel(app.GridLayout2);
            app.text5.Tag = 'text5';
            app.text5.Text = 'Maximum Angular Error (deg)';
            app.maxAngErrorSlider = uislider(app.GridLayout2);
            app.maxAngErrorSlider.Limits = [0 1];
            app.maxAngErrorSlider.MajorTicks = [];
            app.maxAngErrorSlider.ValueChangedFcn = createCallbackFcn(app, @maxAngErrorSlider_Callback, true);
            app.maxAngErrorSlider.ValueChangingFcn = createCallbackFcn(app, @maxAngErrorSlider_Callback, true);
            app.maxAngErrorSlider.MinorTicks = [];
            app.maxAngErrorSlider.Tag = 'maxAngErrorSlider';
            % Show the figure after all components are created
            app.basicTurretedGunEditorGUI.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = basicTurretedGunEditorGUI_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.basicTurretedGunEditorGUI)

                % Execute the startup function
                runStartupFcn(app, @(app)basicTurretedGunEditorGUI_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.basicTurretedGunEditorGUI)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.basicTurretedGunEditorGUI)
        end
    end
end