classdef editShipPidControllersGUI_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        editShipPidControllersGUI  matlab.ui.Figure
        GridLayout                 matlab.ui.container.GridLayout
        uipanel2                   matlab.ui.container.Panel
        GridLayout6                matlab.ui.container.GridLayout
        kdGainSlider               matlab.ui.control.Slider
        text5                      matlab.ui.control.Label
        kiGainSlider               matlab.ui.control.Slider
        text4                      matlab.ui.control.Label
        kpGainSlider               matlab.ui.control.Slider
        text3                      matlab.ui.control.Label
        uipanel3                   matlab.ui.container.Panel
        GridLayout3                matlab.ui.container.GridLayout
        uipanel6                   matlab.ui.container.Panel
        GridLayout5                matlab.ui.container.GridLayout
        setPointUnitLabel          matlab.ui.control.Label
        setPointText               matlab.ui.control.EditField
        uipanel4                   matlab.ui.container.Panel
        GridLayout4                matlab.ui.container.GridLayout
        initCondRateText           matlab.ui.control.EditField
        initConditionsText         matlab.ui.control.EditField
        initCondRateUnitLabel      matlab.ui.control.Label
        initCondUnitLabel          matlab.ui.control.Label
        saveAndCloseButton         matlab.ui.control.Button
        uipanel1                   matlab.ui.container.Panel
        GridLayout2                matlab.ui.container.GridLayout
        computingResponseLabel     matlab.ui.control.Label
        outputAxes                 matlab.ui.control.UIAxes
        pidControllerSelButtonGrp  matlab.ui.container.ButtonGroup
        headingControllerRadioBtn  matlab.ui.control.RadioButton
        speedControllerRadioBtn    matlab.ui.control.RadioButton
        text2                      matlab.ui.control.Label
    end

    
    methods (Access = private)
        function pidController = getSelPidController(app, handles)
                ship = getappdata(handles.editShipPidControllersGUI,'ship');
            
                if(handles.pidControllerSelButtonGrp.SelectedObject == handles.speedControllerRadioBtn)
                    pidController = ship.basicPropagator.speedCntrlr;
                elseif(handles.pidControllerSelButtonGrp.SelectedObject == handles.headingControllerRadioBtn)
                    pidController = ship.basicPropagator.headingCntrlr;
                else
                    error('Unknown PID Controller radio button selection.');
                end
        end
        
        function handles = setupGUI(app, handles)
                pid = getSelPidController(app, handles);
%                 sliderResolution = getappdata(handles.editShipPidControllersGUI,'sliderResolution');
            
                %Set Up Scroll Bars
                app.kpGainSlider.Limits = [0, 100];
                app.kpGainSlider.Value = min(max(pid.getPIDParam(NNS_PidController.PID_KP), 0), 100);
                app.kpGainSlider.MajorTicks = [0 : 10 : 100]; %#ok<*NBRAK2> 
                app.kpGainSlider.MinorTicks = [0 : 02 : 100];
%                 [t, fH] = createDecimalSlider(handles.kpGainSlider.Parent, sliderResolution, handles.kpGainSlider.Position, 0.0, 100, pid.getPIDParam(NNS_PidController.PID_KP), 10, 2);
%                 delete(handles.kpGainSlider);
%                 handles.kpGainSlider = t;
%                 t.JavaComponent.StateChangedCallback = @(hSrc,eventData) kpGainSlider_Callback(hSrc,eventData,handles,fH);

                app.kiGainSlider.Limits = [0, 100];
                app.kiGainSlider.Value = min(max(pid.getPIDParam(NNS_PidController.PID_KI), 0), 100);
                app.kiGainSlider.MajorTicks = [0 : 10 : 100]; %#ok<*NBRAK2> 
                app.kiGainSlider.MinorTicks = [0 : 02 : 100];
%                 [t, fH] = createDecimalSlider(handles.kiGainSlider.Parent, sliderResolution, handles.kiGainSlider.Position, 0.0, 100, pid.getPIDParam(NNS_PidController.PID_KI), 10, 2);
%                 delete(handles.kiGainSlider);
%                 handles.kiGainSlider = t;
%                 t.JavaComponent.StateChangedCallback = @(hSrc,eventData) kiGainSlider_Callback(hSrc,eventData,handles,fH);

                app.kdGainSlider.Limits = [0, 100];
                app.kdGainSlider.Value = min(max(pid.getPIDParam(NNS_PidController.PID_KD), 0), 100);
                app.kdGainSlider.MajorTicks = [0 : 10 : 100]; %#ok<*NBRAK2> 
                app.kdGainSlider.MinorTicks = [0 : 02 : 100];
%                 [t, fH] = createDecimalSlider(handles.kdGainSlider.Parent, sliderResolution, handles.kdGainSlider.Position, 0.0, 100, pid.getPIDParam(NNS_PidController.PID_KD), 10, 2);
%                 delete(handles.kdGainSlider);
%                 handles.kdGainSlider = t;
%                 t.JavaComponent.StateChangedCallback = @(hSrc,eventData) kdGainSlider_Callback(hSrc,eventData,handles,fH);
            
                evt = matlab.ui.eventdata.ValueChangedData(0, 0);
                pidControllerSelButtonGrp_SelectionChangedFcn(app, evt);
        end
        
        function simulateAndPlotResults(app, handles)
                handles.computingResponseLabel.Visible = 'on';
                drawnow nocallbacks;
                try
                    ship = getappdata(handles.editShipPidControllersGUI,'ship');
                    ship.inializePropObj();
                    ship.stateMgr.setZeroPositionAndHeadingAndNoVel();
            
                    pidController = getSelPidController(app, handles);
            
                    speedCntrlr = ship.basicPropagator.speedCntrlr;
                    headingCntrlr = ship.basicPropagator.headingCntrlr;
            
                    if(handles.pidControllerSelButtonGrp.SelectedObject == handles.speedControllerRadioBtn)
                        initCond = str2double(handles.initConditionsText.String);
                        setpoint = str2double(handles.setPointText.String);
            
                        ship.stateMgr.velocity(1) = initCond;
                        pidController.setPIDParam(NNS_PidController.PID_SETPOINT, setpoint);
                        headingCntrlr.setPIDParam(NNS_PidController.PID_SETPOINT, 0);
                    elseif(handles.pidControllerSelButtonGrp.SelectedObject == handles.headingControllerRadioBtn)
                        initCond = rad2deg(angleZero2Pi(deg2rad(str2double(handles.initConditionsText.String))));
                        initCondRate = deg2rad(str2double(handles.initCondRateText.String));
                        setpoint = rad2deg(angleZero2Pi(deg2rad(str2double(handles.setPointText.String))));
            
                        ship.stateMgr.heading = deg2rad(initCond);
                        ship.stateMgr.angRate = initCondRate;
            
                        pidController.setPIDParam(NNS_PidController.PID_SETPOINT, deg2rad(setpoint));
                        speedCntrlr.setPIDParam(NNS_PidController.PID_SETPOINT, 0);
                    else
                        error('Unknown PID Controller radio button selection.');
                    end
            
                    oldArena = ship.arena;
                    newArena = NNS_Arena([-Inf Inf],[-Inf Inf]);
            
                    simClock = NNS_SimClock(0, 20, 0, 1/10);
                    ship.arena = newArena;
                    ship.arena.simClock = simClock;
                    simClock.curSimTime = 0;  %seconds
            
                    timeHistory = simClock.startTime:simClock.timeStep:simClock.endTime;
                    shipVelHistory = zeros(size(timeHistory));
                    shipHeadingHistory = zeros(size(timeHistory));
                    for(t = timeHistory) %#ok<*NO4LP>
                        handles.computingResponseLabel.String = sprintf('Computing Response... %.2f%%%', 100*t/max(timeHistory));
                        drawnow nocallbacks;
            
                        simClock.curSimTime = t;
                        ship.basicPropagator.propagateOneStep(simClock.timeStep, ship.arena.xLims, ship.arena.yLims);
            
                        shipVelHistory(t == timeHistory) = norm(ship.stateMgr.velocity);
                        shipHeadingHistory(t == timeHistory) = rad2deg(ship.stateMgr.heading);
                    end
            
                    cla(handles.outputAxes);
                    hold(handles.outputAxes,'on');
                    plot(handles.outputAxes, [min(timeHistory), max(timeHistory)], [setpoint, setpoint], '--r');
            
                    if(handles.pidControllerSelButtonGrp.SelectedObject == handles.speedControllerRadioBtn)
                        plot(handles.outputAxes, timeHistory, shipVelHistory);
                        ylabel('Speed [m/s]');
                    elseif(handles.pidControllerSelButtonGrp.SelectedObject == handles.headingControllerRadioBtn)
                        plot(handles.outputAxes, timeHistory, shipHeadingHistory);
                        ylabel('Heading [deg]');
                    end
                    xlabel('Time [sec]');
                    grid(handles.outputAxes,'on');
                    hold(handles.outputAxes,'off');
                catch ME
                    warning(ME.message);
                end
                ship.arena = oldArena;
                handles.computingResponseLabel.Visible = 'off';
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function editShipPidControllersGUI_OpeningFcn(app, varargin)
            % Ensure that the app appears on screen when run
            movegui(app.editShipPidControllersGUI, 'onscreen');
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
                % This function has no output args, see OutputFcn.
                % hObject    handle to figure
                % eventdata  reserved - to be defined in a future version of MATLAB
                % handles    structure with handles and user data (see GUIDATA)
                % varargin   command line arguments to editShipPidControllersGUI (see VARARGIN)
            
                % Choose default command line output for editShipPidControllersGUI
                handles.output = hObject;

                movegui(app.editShipPidControllersGUI, "center");
            
                if(numel(varargin) >= 1)
                    ship = varargin{1};
                else
                    arena = NNS_Arena([-25 25], [-25 25]);
                    ship = NNS_Ship.createDefaultBasicShip(arena);
                end
                setappdata(hObject,'ship',ship);
                setappdata(hObject,'okayToRunSimulation',true);
                setappdata(hObject,'sliderResolution',1E-3);
            
                %set up GUI
                handles = setupGUI(app, handles);
            
                % Update handles structure
                guidata(hObject, handles);
        end

        % Value changed function: initCondRateText
        function initCondRateText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            hObject = app.initCondRateText;

            % hObject    handle to initCondRateText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of initCondRateText as text
            %        str2double(get(hObject,'String')) returns contents of initCondRateText as a double
                newInput = attemptStrEval(hObject.Value);
                hObject.Value = newInput;
            
                errMsg = {};
            
                x = str2double(get(handles.setPointText,'String'));
                enteredStr = get(handles.setPointText,'String');
                numberName = 'Initial Condition (Lower) Value';
                lb = -Inf;
                ub = Inf;
                isInt = false;
                errMsg = validateNumber(x, numberName, lb, ub, isInt, errMsg, enteredStr);
            
                if(isempty(errMsg))
                    hObject.UserData = newInput;
            
                    okayToRunSimulation = getappdata(handles.editShipPidControllersGUI,'okayToRunSimulation');
                    if(okayToRunSimulation)
                        simulateAndPlotResults(app, handles);
                    end
                else
                    msgbox(errMsg,'Initial Conditon Input Error','error');
                    set(hObject, 'String', hObject.UserData);
                end
        end

        % Value changed function: initConditionsText
        function initConditionsText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            hObject = app.initConditionsText;

                % hObject    handle to initConditionsText (see GCBO)
                % eventdata  reserved - to be defined in a future version of MATLAB
                % handles    structure with handles and user data (see GUIDATA)
            
                % Hints: get(hObject,'String') returns contents of initConditionsText as text
                %        str2double(get(hObject,'String')) returns contents of initConditionsText as a double
                newInput = attemptStrEval(hObject.Value);
                hObject.Value = newInput;
            
                errMsg = {};
            
                x = str2double(get(handles.setPointText,'String'));
                enteredStr = get(handles.setPointText,'String');
                numberName = 'Initial Condition (Upper) Value';
                lb = -Inf;
                ub = Inf;
                isInt = false;
                errMsg = validateNumber(x, numberName, lb, ub, isInt, errMsg, enteredStr);
            
                if(isempty(errMsg))
                    hObject.UserData = newInput;
            
                    okayToRunSimulation = getappdata(handles.editShipPidControllersGUI,'okayToRunSimulation');
                    if(okayToRunSimulation)
                        simulateAndPlotResults(app, handles);
                    end
                else
                    msgbox(errMsg,'Initial Conditon Input Error','error');
                    set(hObject, 'String', hObject.UserData);
                end
        end

        % Value changed function: kdGainSlider
        function kdGainSlider_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event);
            
                % hObject    handle to kdGainSlider (see GCBO)
                % eventdata  reserved - to be defined in a future version of MATLAB
                % handles    structure with handles and user data (see GUIDATA)
            
                % Hints: get(hObject,'Value') returns position of slider
                %        get(hObject,'Min') and get(hObject,'Max') to determine range of slider
                pidController = getSelPidController(app, handles);
%                 cb(hObject,eventdata);
                okayToRunSimulation = getappdata(handles.editShipPidControllersGUI,'okayToRunSimulation');
            
%                 ST = dbstack();
%                 csNames = {ST.name};
        
               pidController.setPIDParam(NNS_PidController.PID_KD, app.kdGainSlider.Value);
                if(okayToRunSimulation)
                    setappdata(handles.editShipPidControllersGUI,'okayToRunSimulation', false);
                    simulateAndPlotResults(app, handles);
                    setappdata(handles.editShipPidControllersGUI,'okayToRunSimulation', true);
                end
        end

        % Value changed function: kiGainSlider
        function kiGainSlider_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); 
            
                % hObject    handle to kiGainSlider (see GCBO)
                % eventdata  reserved - to be defined in a future version of MATLAB
                % handles    structure with handles and user data (see GUIDATA)
            
                % Hints: get(hObject,'Value') returns position of slider
                %        get(hObject,'Min') and get(hObject,'Max') to determine range of slider
                pidController = getSelPidController(app, handles);
%                 cb(hObject,eventdata);
                okayToRunSimulation = getappdata(handles.editShipPidControllersGUI,'okayToRunSimulation');
            
%                 ST = dbstack();
%                 csNames = {ST.name};
        
                pidController.setPIDParam(NNS_PidController.PID_KI, app.kiGainSlider.Value);
                if(okayToRunSimulation)
                    setappdata(handles.editShipPidControllersGUI,'okayToRunSimulation', false);
                    simulateAndPlotResults(app, handles);
                    setappdata(handles.editShipPidControllersGUI,'okayToRunSimulation', true);
                end
        end

        % Value changed function: kpGainSlider
        function kpGainSlider_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); 
            
                % hObject    handle to kpGainSlider (see GCBO)
                % eventdata  reserved - to be defined in a future version of MATLAB
                % handles    structure with handles and user data (see GUIDATA)
            
                % Hints: get(hObject,'Value') returns position of slider
                %        get(hObject,'Min') and get(hObject,'Max') to determine range of slider
                pidController = getSelPidController(app, handles);
%                 cb(hObject,eventdata);
                okayToRunSimulation = getappdata(handles.editShipPidControllersGUI,'okayToRunSimulation');
            
%                 ST = dbstack();
%                 csNames = {ST.name};
        
                pidController.setPIDParam(NNS_PidController.PID_KP, app.kpGainSlider.Value);
                if(okayToRunSimulation)
                    setappdata(handles.editShipPidControllersGUI,'okayToRunSimulation', false);
                    simulateAndPlotResults(app, handles);
                    setappdata(handles.editShipPidControllersGUI,'okayToRunSimulation', true);
                end
        end

        % Selection changed function: pidControllerSelButtonGrp
        function pidControllerSelButtonGrp_SelectionChangedFcn(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [~, ~, handles] = convertToGUIDECallbackArguments(app, event);
            hObject = app.pidControllerSelButtonGrp;

                % hObject    handle to the selected object in pidControllerSelButtonGrp
                % eventdata  reserved - to be defined in a future version of MATLAB
                % handles    structure with handles and user data (see GUIDATA)
                pidController = getSelPidController(app, handles);
%                 sliderResolution = getappdata(handles.editShipPidControllersGUI,'sliderResolution');
            
                if(handles.pidControllerSelButtonGrp.SelectedObject == handles.speedControllerRadioBtn)
                    ship = getappdata(handles.editShipPidControllersGUI,'ship');
                    [~, ~, ~, termVel] = getShipLinearTermVel(ship);
            
                    handles.initCondUnitLabel.String = 'm/s';
                    handles.initConditionsText.String = '0.0';
                    handles.initCondRateUnitLabel.String = 'm/s';
                    handles.initCondRateText.String = '0.0';
                    handles.setPointUnitLabel.String = 'm/s';
                    handles.setPointText.String = num2str(termVel,3);
            
                    handles.initCondRateText.Enable = 'off';
            
                elseif(handles.pidControllerSelButtonGrp.SelectedObject == handles.headingControllerRadioBtn)
                    handles.initCondUnitLabel.String = 'deg';
                    handles.initConditionsText.String = '0.0';
                    handles.initCondRateUnitLabel.String = 'deg/s';
                    handles.initCondRateText.String = '0.0';
                    handles.setPointUnitLabel.String = 'deg';
                    handles.setPointText.String = '180';
            
                    handles.initCondRateText.Enable = 'on';
                else
                    error('Unknown PID Controller radio button selection.');
                end
            
                setappdata(handles.editShipPidControllersGUI,'okayToRunSimulation',false);
            
                evt = matlab.ui.eventdata.ValueChangedData(0, 0);
                setPointText_Callback(app, evt);
                initConditionsText_Callback(app, evt);
                initCondRateText_Callback(app, evt);
            
                setappdata(hObject,'okayToRunSimulation',false);
%                 handles.kpGainSlider.JavaComponent.setValue(pidController.getPIDParam(NNS_PidController.PID_KP)/sliderResolution);
%                 handles.kiGainSlider.JavaComponent.setValue(pidController.getPIDParam(NNS_PidController.PID_KI)/sliderResolution);
%                 handles.kdGainSlider.JavaComponent.setValue(pidController.getPIDParam(NNS_PidController.PID_KD)/sliderResolution);
                app.kpGainSlider.Value = min(max(pidController.getPIDParam(NNS_PidController.PID_KP), 0), 100);
                app.kiGainSlider.Value = min(max(pidController.getPIDParam(NNS_PidController.PID_KI), 0), 100);
                app.kdGainSlider.Value = min(max(pidController.getPIDParam(NNS_PidController.PID_KD), 0), 100);
                setappdata(handles.editShipPidControllersGUI,'okayToRunSimulation',true);
            
                simulateAndPlotResults(app, handles);
        end

        % Button pushed function: saveAndCloseButton
        function saveAndCloseButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
                % hObject    handle to saveAndCloseButton (see GCBO)
                % eventdata  reserved - to be defined in a future version of MATLAB
                % handles    structure with handles and user data (see GUIDATA)
                close(handles.editShipPidControllersGUI);
        end

        % Value changed function: setPointText
        function setPointText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [~, ~, handles] = convertToGUIDECallbackArguments(app, event);
            hObject = app.setPointText;

            % hObject    handle to setPointText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
        
            % Hints: get(hObject,'String') returns contents of setPointText as text
            %        str2double(get(hObject,'String')) returns contents of setPointText as a double
            newInput = attemptStrEval(app.setPointText.Value);
            app.setPointText.Value = newInput;
        
            errMsg = {};
        
            x = str2double(get(handles.setPointText,'String'));
            enteredStr = get(handles.setPointText,'String');
            numberName = 'PID Controller Set Point Value';
            lb = -Inf;
            ub = Inf;
            isInt = false;
            errMsg = validateNumber(x, numberName, lb, ub, isInt, errMsg, enteredStr);
        
            if(isempty(errMsg))
                hObject.UserData = newInput;
        
                okayToRunSimulation = getappdata(handles.editShipPidControllersGUI,'okayToRunSimulation');
                if(okayToRunSimulation)
                    simulateAndPlotResults(app, handles);
                end
            else
                msgbox(errMsg,'Set Point Input Error','error');
                set(hObject, 'String', hObject.UserData);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.editShipPidControllersGUI = uifigure('Visible', 'off');
            app.editShipPidControllersGUI.Name = 'PID Controller Editor';
            app.editShipPidControllersGUI.Tag = 'editShipPidControllersGUI';
            app.GridLayout = uigridlayout(app.editShipPidControllersGUI);
            app.text2 = uilabel(app.GridLayout);
            app.text2.Tag = 'text2';
            app.text2.Text = 'Ship PID Controller Editor';
            app.pidControllerSelButtonGrp = uibuttongroup(app.GridLayout);
            app.pidControllerSelButtonGrp.SelectionChangedFcn = createCallbackFcn(app, @pidControllerSelButtonGrp_SelectionChangedFcn, true);
            app.pidControllerSelButtonGrp.Title = 'PID Controller';
            app.pidControllerSelButtonGrp.Tag = 'pidControllerSelButtonGrp';
            app.speedControllerRadioBtn = uiradiobutton(app.pidControllerSelButtonGrp);
            app.speedControllerRadioBtn.Tag = 'speedControllerRadioBtn';
            app.speedControllerRadioBtn.Text = 'Speed Controller';
            app.headingControllerRadioBtn = uiradiobutton(app.pidControllerSelButtonGrp);
            app.headingControllerRadioBtn.Tag = 'headingControllerRadioBtn';
            app.headingControllerRadioBtn.Text = 'Heading Controller';
            app.headingControllerRadioBtn.Value = true;
            app.uipanel1 = uipanel(app.GridLayout);
            app.uipanel1.Title = 'Controller Output';
            app.uipanel1.Tag = 'uipanel1';
            app.GridLayout2 = uigridlayout(app.uipanel1);
            app.outputAxes = uiaxes(app.GridLayout2);
            app.outputAxes.Tag = 'outputAxes';
            app.computingResponseLabel = uilabel(app.GridLayout2);
            app.computingResponseLabel.Tag = 'computingResponseLabel';
            app.computingResponseLabel.Text = 'Computing Response...';
            app.saveAndCloseButton = uibutton(app.GridLayout, 'push');
            app.saveAndCloseButton.ButtonPushedFcn = createCallbackFcn(app, @saveAndCloseButton_Callback, true);
            app.saveAndCloseButton.Tag = 'saveAndCloseButton';
            app.saveAndCloseButton.Text = 'Close';
            app.uipanel3 = uipanel(app.GridLayout);
            app.uipanel3.Title = 'Controller Inputs';
            app.uipanel3.Tag = 'uipanel3';
            app.GridLayout3 = uigridlayout(app.uipanel3);
            app.uipanel4 = uipanel(app.GridLayout3);
            app.uipanel4.Title = 'Initial Conditions';
            app.uipanel4.Tag = 'uipanel4';
            app.GridLayout4 = uigridlayout(app.uipanel4);
            app.initCondUnitLabel = uilabel(app.GridLayout4);
            app.initCondUnitLabel.Tag = 'initCondUnitLabel';
            app.initCondUnitLabel.Text = 'unit';
            app.initCondRateUnitLabel = uilabel(app.GridLayout4);
            app.initCondRateUnitLabel.Tag = 'initCondRateUnitLabel';
            app.initCondRateUnitLabel.Text = 'unit';
            app.initConditionsText = uieditfield(app.GridLayout4, 'text');
            app.initConditionsText.ValueChangedFcn = createCallbackFcn(app, @initConditionsText_Callback, true);
            app.initConditionsText.Tag = 'initConditionsText';
            app.initConditionsText.Value = '0.0';
            app.initCondRateText = uieditfield(app.GridLayout4, 'text');
            app.initCondRateText.ValueChangedFcn = createCallbackFcn(app, @initCondRateText_Callback, true);
            app.initCondRateText.Tag = 'initCondRateText';
            app.initCondRateText.Value = '0.0';
            app.uipanel6 = uipanel(app.GridLayout3);
            app.uipanel6.Title = 'Set Point';
            app.uipanel6.Tag = 'uipanel6';
            app.GridLayout5 = uigridlayout(app.uipanel6);
            app.setPointText = uieditfield(app.GridLayout5, 'text');
            app.setPointText.ValueChangedFcn = createCallbackFcn(app, @setPointText_Callback, true);
            app.setPointText.Tag = 'setPointText';
            app.setPointText.Value = '1.0';
            app.setPointUnitLabel = uilabel(app.GridLayout5);
            app.setPointUnitLabel.Tag = 'setPointUnitLabel';
            app.setPointUnitLabel.Text = 'unit';
            app.uipanel2 = uipanel(app.GridLayout);
            app.uipanel2.Title = 'Controller Gains';
            app.uipanel2.Tag = 'uipanel2';
            app.GridLayout6 = uigridlayout(app.uipanel2);
            app.text3 = uilabel(app.GridLayout6);
            app.text3.Tag = 'text3';
            app.text3.Text = 'Proportional Gain (Kp)';
            app.kpGainSlider = uislider(app.GridLayout6);
            app.kpGainSlider.Limits = [0 1];
            app.kpGainSlider.MajorTicks = [];
            app.kpGainSlider.ValueChangedFcn = createCallbackFcn(app, @kpGainSlider_Callback, true);
            app.kpGainSlider.MinorTicks = [];
            app.kpGainSlider.Tag = 'kpGainSlider';
            app.text4 = uilabel(app.GridLayout6);
            app.text4.Tag = 'text4';
            app.text4.Text = 'Integral Gain (Ki)';
            app.kiGainSlider = uislider(app.GridLayout6);
            app.kiGainSlider.Limits = [0 1];
            app.kiGainSlider.MajorTicks = [];
            app.kiGainSlider.ValueChangedFcn = createCallbackFcn(app, @kiGainSlider_Callback, true);
            app.kiGainSlider.MinorTicks = [];
            app.kiGainSlider.Tag = 'kiGainSlider';
            app.text5 = uilabel(app.GridLayout6);
            app.text5.Tag = 'text5';
            app.text5.Text = 'Derivative Gain (Kd)';
            app.kdGainSlider = uislider(app.GridLayout6);
            app.kdGainSlider.Limits = [0 1];
            app.kdGainSlider.MajorTicks = [];
            app.kdGainSlider.ValueChangedFcn = createCallbackFcn(app, @kdGainSlider_Callback, true);
            app.kdGainSlider.MinorTicks = [];
            app.kdGainSlider.Tag = 'kdGainSlider';
            % Show the figure after all components are created
            app.editShipPidControllersGUI.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = editShipPidControllersGUI_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.editShipPidControllersGUI)

                % Execute the startup function
                runStartupFcn(app, @(app)editShipPidControllersGUI_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.editShipPidControllersGUI)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.editShipPidControllersGUI)
        end
    end
end