classdef shipCompEditorGUI_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        shipCompEditorGUI         matlab.ui.Figure
        shipInfoMenu              matlab.ui.container.Menu
        setShipNameAndDesc        matlab.ui.container.Menu
        GridLayout                matlab.ui.container.GridLayout
        GridLayout2               matlab.ui.container.GridLayout
        GridLayout4               matlab.ui.container.GridLayout
        editSelCompButton         matlab.ui.control.Button
        uipanel2                  matlab.ui.container.Panel
        GridLayout5               matlab.ui.container.GridLayout
        selCompSumPanel           matlab.ui.container.Panel
        GridLayout8               matlab.ui.container.GridLayout
        selectedCompSumText       matlab.ui.control.Label
        uipanel6                  matlab.ui.container.Panel
        GridLayout7               matlab.ui.container.GridLayout
        vehPowerDataText          matlab.ui.control.Label
        uipanel5                  matlab.ui.container.Panel
        GridLayout6               matlab.ui.container.GridLayout
        vehDynDataText            matlab.ui.control.Label
        GridLayout3               matlab.ui.container.GridLayout
        CompTree                  matlab.ui.container.Tree
        saveAndCloseButton        matlab.ui.control.Button
        editPidControllersButton  matlab.ui.control.Button
        text2                     matlab.ui.control.Label
        shipCanvasAxes            matlab.ui.control.UIAxes
        titleText                 matlab.ui.control.Label
    end


    properties (Access = private)
        textBoxTooltip % Description
        textBoxTooltip2
    end

    methods (Access = private)
        function addVehCompToShip(app, newComp, ship, shpGrp, handles)
            ship.components.addComponent(newComp);
            createCPolyForComp(app, newComp, shpGrp, ship, handles);

            updateVehDynSumText(ship, handles);
            updateVehPowerSumText(ship, handles);
        end

        function newPos = cPolyPosConstraintFunc(app, pos, fhAxLimits)
            pos = fhAxLimits(pos);
            c = ShipGraphicalObjects.getCentroidOfVertices(pos);
            cRounded = round(c,1);
            deltaPos = cRounded - c;

            newPos = pos - deltaPos;
        end

        function compMovedCallback(app, ~, curCPoly, shpGrp, handles)
            intersectionExceptions = getappdata(handles.shipCompEditorGUI,'intersectionExceptions');

            comp = shpGrp.cPolyCompRefs.getCompForCpoly(curCPoly);
            comp.relPos = (shpGrp.getCentroidOfImpoly(curCPoly) - shpGrp.getSpolyCentroid())';

            updateCPolyEdgesBasedOnValidness(app, shpGrp, intersectionExceptions);

            sortAxes2dImpolyChildren(app.shipCanvasAxes);
            updateDataDisplays(handles);
        end

        function createCPolyForComp(app, c, shpGrp, ship, handles)
            c.drawObjectOnAxes(app.shipCanvasAxes);
            patch = c.drawer.getPatchForDrawnComponents();

            cPoly = getNewCPolyForShipCanvass(app, patch.Vertices+shpGrp.getSpolyCentroid()+c.relPos', shpGrp, ship, handles);
            shpGrp.addCpoly(cPoly,c);
            cPChildren = get(cPoly,'Children');
            pp = cPChildren(end);
            pp.FaceColor = patch.FaceColor;
            pp.EdgeColor = patch.EdgeColor;

            c.destroyGraphics();
            el = addlistener(c,'ShipEditorCompNeedsRedraw', @(src,evt) replaceCPolyForEventCallback(app, src,evt, shpGrp, handles));
            shpGrp.els(end+1) = el;
        end

        function createDefaultSPoly(app, shpGrp, ship, handles)
            initShipPos = [-1 0; 1 0; 1 3; 0 4; -1 3];
            initShipPos = initShipPos - ShipGraphicalObjects.getCentroidOfVertices(initShipPos);
            fh = makeConstrainToRectFcn('impoly',get(app.shipCanvasAxes,'XLim'),get(app.shipCanvasAxes,'YLim'));

            sPoly = impoly(app.shipCanvasAxes,initShipPos,'Closed',true,'PositionConstraintFcn',@(p) polyPosConstraintFunc(app, p, fh));
            sPoly.setPosition(polyPosConstraintFunc(app, sPoly.getPosition(), fh));
            sPoly.Deletable = false;
            sPoly.setColor('k');

            pp = findall(sPoly,'Type','patch');
            pp = pp(1);
            pp.FaceColor = [211,211,211]/255;
            pp.FaceAlpha = 0.75;
            pp.EdgeColor = 'k';

            shpGrp.setSPoly(sPoly);
            sPoly.addNewPositionCallback(@(p) moveShipComps(app, p, shpGrp, handles));
            sPoly.addNewPositionCallback(@(p) updateShipHullVerts(app, p, ship.hull));

            %delete built in context menu
            c = get(sPoly,'Children');
            cm = uicontextmenu(app.shipCompEditorGUI);
            cmenu_obj = findobj(c,'Type','line','-or','Type','patch');
            set(cmenu_obj,'uicontextmenu',cm);
        end

        function cPoly = getNewCPolyForShipCanvass(app, vertices, shpGrp, ship, handles)
            fh = makeConstrainToRectFcn('impoly',get(app.shipCanvasAxes,'XLim'),get(app.shipCanvasAxes,'YLim'));
            cPoly = impoly(app.shipCanvasAxes,vertices,'Closed',true,'PositionConstraintFcn',@(p) cPolyPosConstraintFunc(app, p, fh));
            cPoly.setVerticesDraggable(false);
            cPoly.setColor('k');
            cPoly.addNewPositionCallback(@(p) compMovedCallback(app, p, cPoly, shpGrp, handles));

            el = addlistener(cPoly,'ObjectBeingDestroyed',@(x1,x2) removeCPolyFromGraphics(app, x1,x2, shpGrp, ship, handles));
            shpGrp.els(end+1) = el;

            pp = findall(cPoly,'Type','patch');
            pp = pp(1);
            pp.FaceColor = 'g';
            pp.EdgeColor = 'k';

            pl = findall(cPoly,'Type','line');
            for(i=1:length(pl))
                pl(i).Visible = 'off';
            end

            sortAxes2dImpolyChildren(app.shipCanvasAxes);

            %delete built in context menu
            c = get(cPoly,'Children');
            cm = uicontextmenu(app.shipCompEditorGUI);
            cmenu_obj = findobj(c,'Type','line','-or','Type','patch');
            set(cmenu_obj,'uicontextmenu',cm);
        end

        function moveShipComps(app, pos, shpGrp, handles)
            [cx,cy]=centroid(polyshape(pos));
            [ocx, ocy] = centroid(polyshape(shpGrp.oldPos));

            deltaPos = [cx,cy] - [ocx, ocy];

            if(size(pos) == size(shpGrp.oldPos))
                if(sum(any(abs(pos - shpGrp.oldPos) > 0,2)) > 1) %we are translating the ship, not moving a vertex
                    for(i=1:length(shpGrp.cPolys)) %#ok<*NO4LP>
                        if(isvalid(shpGrp.cPolys(i)))
                            curCPos = shpGrp.cPolys(i).getPosition();
                            if(any(inpolygon(curCPos(:,1),curCPos(:,2), shpGrp.oldPos(:,1), shpGrp.oldPos(:,2))==1)) %only move if at least some of the component is inside the ship.
                                shpGrp.cPolys(i).setPosition(curCPos + deltaPos);
                            end
                        end
                    end
                end

                for(i=1:length(shpGrp.cPolys))
                    compMovedCallback(app, shpGrp.cPolys(i).getPosition(), shpGrp.cPolys(i), shpGrp, handles);
                end
            end

            shpGrp.oldPos = pos;
        end

        function newPos = polyPosConstraintFunc(app, pos, fhAxesLimit)
            newPos = fhAxesLimit(pos);
        end

        function removeCPolyFromGraphics(app, cPoly, ~, shpGrp, ship, handles)
            c = shpGrp.cPolyCompRefs.getCompForCpoly(cPoly);
            ship.components.removeComponent(c);
            shpGrp.removeCpoly(cPoly);
            delete(cPoly);

            updateVehDynSumText(ship, handles);
            updateVehPowerSumText(ship, handles);
            selectComponent(app, [], ship, handles);
        end

        function replaceCPolyForComp(app, c, shpGrp, handles)
            c.drawObjectOnAxes(app.shipCanvasAxes);
            patch = c.drawer.getPatchForDrawnComponents();

            cPoly = shpGrp.cPolyCompRefs.getCPolyForComp(c);
            oldPos = shpGrp.getCentroidOfImpoly(cPoly);
            newPos = shpGrp.getCentroidOfVertices(patch.Vertices);

            deltaPos = newPos - oldPos;
            cPoly.setPosition(patch.Vertices - deltaPos);

            c.destroyGraphics();
        end

        function replaceCPolyForEventCallback(app, src, ~, shpGrp, handles)
            replaceCPolyForComp(app, src, shpGrp, handles);
        end

        function selectComponent(app, selComp, ship, handles)
            if(isempty(selComp))
                selComp = ship.hull;
            end

            setappdata(handles.shipCompEditorGUI,'selectedComponent',selComp);
            updateDataDisplays(handles);
        end

        function handles = setupGUI(app, ship, shpGrp, handles)
            createDefaultSPoly(app, shpGrp, ship, handles);
            shpGrp.sPoly.setPosition(ship.hull.hullVerts);

            updateVehDynSumText(ship, handles);
            updateVehPowerSumText(ship, handles);
            selectComponent(app, [], ship, handles);

            el = addlistener(ship.hull,'hullVerts','PostSet', @(src,evt) shipCompEdit_HullVertsUpdated(src, evt, ship, handles));
            shpGrp.els(end+1) = el;

            cH = NNS_ComponentHierarchy.getDefaultComponentHierarchy();
            setappdata(handles.shipCompEditorGUI,'CompHier',cH);
            treeNodes = cH.getTreeNodes(app.CompTree);
            for(i=1:length(treeNodes))
                treeNodes(i).Parent = app.CompTree;
            end
            expand(app.CompTree);

            for(i=1:length(ship.components.components))
                c = ship.components.components(i);
                if(isa(c,'NNS_AbstractDrawableVehicleComponent') && not(isa(c,'NNS_AbstractHull')))
                    createCPolyForComp(app, c, shpGrp, ship, handles);
                end
            end

            sortAxes2dImpolyChildren(app.shipCanvasAxes);

            intersectionExceptions = getappdata(handles.shipCompEditorGUI,'intersectionExceptions');
            updateCPolyEdgesBasedOnValidness(app, shpGrp, intersectionExceptions);

            app.textBoxTooltip = annotation(app.shipCompEditorGUI, 'textbox', 'Visible','off', 'Interpreter','none', ...
                'BackgroundColor',[255,255,224]/255, 'Margin',0, ...
                'HorizontalAlignment','center', 'VerticalAlignment','middle', ...
                'HitTest','off', 'PickableParts','none');

            app. textBoxTooltip2 = text(app.shipCanvasAxes, 0,0, 'Test', 'Visible','on', 'Interpreter','none', ...
                'BackgroundColor',[255,255,224]/255, 'Margin',1, ...
                'HorizontalAlignment','center', 'VerticalAlignment','middle', ...
                'HitTest','off', 'PickableParts','none', 'Units','normalized');
        end

        function updateCPolyEdgesBasedOnValidness(app, shpGrp, intersectionExceptions)
            cPolys = shpGrp.cPolys;
            for(i=1:length(cPolys))
                cPoly = cPolys(i);

                isValid = validateCompPosition(app, cPoly.getPosition(), cPoly, shpGrp, intersectionExceptions);

                pp = findall(cPoly,'Type','patch');
                pp = pp(1);
                if(isValid)
                    pp.EdgeColor = 'k';
                else
                    pp.EdgeColor = 'r';
                end
            end
        end

        function updateShipHullVerts(app, pos, hull)
            hull.hullVerts = pos;
        end

        function isValid = validateCompPosition(app, curCPos, cPoly, shpGrp, intersectionExceptions)
            curSPos = shpGrp.sPoly.getPosition();
            inShipEdge = inpolygon(curCPos(:,1),curCPos(:,2),curSPos(:,1),curSPos(:,2));

            inOtherComp = [];
            cPolys = shpGrp.cPolys;
            for(i=1:length(cPolys))
                oCpoly = cPolys(i);

                if(not(cPoly == oCpoly))
                    oPos = oCpoly.getPosition();

                    comp1 = shpGrp.cPolyCompRefs.getCompForCpoly(cPoly);
                    comp2 = shpGrp.cPolyCompRefs.getCompForCpoly(oCpoly);

                    if(not(intersectionExceptions.hasIntersectionException(comp1, comp2)))
                        inOtherComp = [inOtherComp; inpolygon(curCPos(:,1),curCPos(:,2),oPos(:,1),oPos(:,2))]; %#ok<AGROW>
                    end
                end
            end

            if(all(inShipEdge) && all(inOtherComp == 0))
                isValid = true;
            else
                isValid = false;
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function shipCompEditorGUI_OpeningFcn(app, varargin)
            % Ensure that the app appears on screen when run
            movegui(app.shipCompEditorGUI, 'center');

            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>

            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to shipCompEditorGUI (see VARARGIN)

            % Choose default command line output for shipCompEditorGUI
            handles.output = hObject;

            if(numel(varargin) >= 1)
                ship = varargin{1};
            else
                arena = NNS_Arena([-25 25], [-25 25]);
                ship = NNS_Ship.createDefaultBasicShip(arena);
            end
            ship.active = true;
            setappdata(hObject,'ship',ship);

            shpGrp = ShipGraphicalObjects();
            setappdata(hObject,'shipGraphics',shpGrp);
            setappdata(hObject,'intersectionExceptions',NNS_ComponentIntersectionExceptionSet.getDefaultComponentIntersectionExceptionSet());

            handles = setupGUI(app, ship, shpGrp, handles);

            % Update handles structure
            guidata(hObject, handles);

            % UIWAIT makes shipCompEditorGUI wait for user response (see UIRESUME)
            uiwait(handles.shipCompEditorGUI);
        end

        % Callback function
        function compListbox_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to compListbox (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: contents = cellstr(get(hObject,'String')) returns compListbox contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from compListbox
            if(strcmpi(handles.shipCompEditorGUI.SelectionType,'open'))
                contents = cellstr(get(hObject,'String'));
                sel = strip(contents{get(hObject,'Value')});

                shpGrp = getappdata(handles.shipCompEditorGUI,'shipGraphics');
                ship = getappdata(handles.shipCompEditorGUI,'ship');

                newComp = NNS_ComponentHierarchy.getDefaultComponentForTypeName(sel, ship);
                if(not(isempty(newComp)))
                    addVehCompToShip(app, newComp, ship, shpGrp, handles);

                    intersectionExceptions = getappdata(handles.shipCompEditorGUI,'intersectionExceptions');
                    updateCPolyEdgesBasedOnValidness(app, shpGrp, intersectionExceptions);
                end
            end
        end

        % Button pushed function: editPidControllersButton
        function editPidControllersButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to editPidControllersButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            ship = getappdata(handles.shipCompEditorGUI,'ship');
            editShipPidControllersGUI_App(ship);
        end

        % Button pushed function: editSelCompButton
        function editSelCompButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to editSelCompButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            selComp = getappdata(handles.shipCompEditorGUI,'selectedComponent');
            selComp.createCompPropertiesEditorFigure(handles);
        end

        % Button pushed function: saveAndCloseButton
        function saveAndCloseButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to saveAndCloseButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            close(handles.shipCompEditorGUI);
        end

        % Menu selected function: setShipNameAndDesc
        function setShipNameAndDesc_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to setShipNameAndDesc (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            ship = getappdata(handles.shipCompEditorGUI,'ship');

            prompt = {'Enter the ship name:', 'Enter the ship description:'};
            dlg_title = 'Ship Information';
            num_lines = [1, 50;
                10, 50];
            defAns = {ship.name, ship.desc};

            answer = inputdlg(prompt,dlg_title,num_lines,defAns);

            ship.name = answer{1};
            ship.desc = answer{2};
        end

        % Close request function: shipCompEditorGUI
        function shipCompEditorGUI_CloseRequestFcn(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to shipCompEditorGUI (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hint: delete(hObject) closes the figure
            shpGrp = getappdata(handles.shipCompEditorGUI,'shipGraphics');
            intersectionExceptions = getappdata(handles.shipCompEditorGUI,'intersectionExceptions');

            cPolys = shpGrp.cPolys;
            isValidArr = ones([length(cPolys),1]);
            problemComps = {};
            for(i=1:length(cPolys))
                cPoly = cPolys(i);

                isValidArr(i) = validateCompPosition(app, cPoly.getPosition(), cPoly, shpGrp, intersectionExceptions);

                if(isValidArr(i) == 0)
                    problemComps(end+1) = {shpGrp.cPolyCompRefs.getCompForCpoly(cPoly).getShortCompName()}; %#ok<AGROW>
                end
            end

            if(all(isValidArr))
                shpGrp.destroyAllListeners();
                close(app.shipCompEditorGUI);
            else
                warnMsg = sprintf('The following components are in error because they are either outside the bounds of the ship or conflict with another component.  You must correct these errors before returning to the main menu.\n\n');
                for(i=1:length(problemComps))
                    warnMsg = [warnMsg, sprintf('\t%s\n', problemComps{i})]; %#ok<AGROW>
                end
                warndlg(warnMsg);
            end
        end

        % Window button down function: shipCompEditorGUI
        function shipCompEditorGUI_WindowButtonDownFcn(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to shipCompEditorGUI (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            shpGrp = getappdata(handles.shipCompEditorGUI,'shipGraphics');
            ship = getappdata(handles.shipCompEditorGUI,'ship');

            cp = get(app.shipCanvasAxes, 'CurrentPoint');
            cp = cp(1,1:2);
            if(all(abs(cp) < 5))
                poly = shpGrp.getPolyForPoint(cp);
                selComp = shpGrp.selectPoly(poly);

                app.selectComponent(selComp, ship, handles);
            end
        end

        % Window button motion function: shipCompEditorGUI
        function shipCompEditorGUI_WindowButtonMotionFcn(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [~, ~, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to shipCompEditorGUI (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            shpGrp = getappdata(app.shipCompEditorGUI,'shipGraphics');
            %     ship = getappdata(handles.shipCompEditorGUI,'ship');

            C = get(app.shipCanvasAxes, 'CurrentPoint');
            cX = C(1,1);
            cY = C(1,2);

            %                 cPolys = shpGrp.cPolys;
            hObj = hittest(app.shipCompEditorGUI);
            if(isa(hObj,'matlab.graphics.primitive.Patch'))
                [Xf, Yf] = ds2nfu(app.shipCanvasAxes, cX, cY);
                %                     tttPos = app.textBoxTooltip.Position;
                %
                %                     newPos = [Xf, Yf];
                %                     if(cX > 0)
                %                         newPos(1) = Xf - tttPos(3);
                %                     end
                %
                %                     if(cY > 0)
                %                         newPos(2) = Yf - tttPos(4);
                %                     end
                %
                %                     tttPos(1:2) = newPos;
                %                     app.textBoxTooltip.Position = tttPos;
                app.textBoxTooltip2.Position = [Xf, Yf];

                comp = shpGrp.cPolyCompRefs.getCompForCpoly(shpGrp.getPolyForPoint([cX,cY]));
                if(not(isempty(comp)))
                    str = comp.getShortCompName();
                    if(not(isempty(str)))
                        app.textBoxTooltip2.String = sprintf('%s (%s)', comp.typeName, str);
                        app.textBoxTooltip2.Visible = 'on';
                        drawnow limitrate;
                    else
                        app.textBoxTooltip2.Visible = 'off';
                    end
                else
                    app.textBoxTooltip2.Visible = 'off';
                end
            else
                app.textBoxTooltip2.Visible = 'off';
            end
        end

        % Window key press function: shipCompEditorGUI
        function shipCompEditorGUI_WindowKeyPressFcn(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to shipCompEditorGUI (see GCBO)
            % eventdata  structure with the following fields (see MATLAB.UI.FIGURE)
            %	Key: name of the key that was pressed, in lower case
            %	Character: character interpretation of the key(s) that was pressed
            %	Modifier: name(s) of the modifier key(s) (i.e., control, shift) pressed
            % handles    structure with handles and user data (see GUIDATA)
            shpGrp = getappdata(handles.shipCompEditorGUI,'shipGraphics');
            selComp = getappdata(handles.shipCompEditorGUI,'selectedComponent');
            ship = getappdata(handles.shipCompEditorGUI,'ship');

            if(not(isempty(selComp)))
                if(isa(selComp,'NNS_AbstractHull'))
                    poly = shpGrp.sPoly;
                else
                    poly = shpGrp.cPolyCompRefs.getCPolyForComp(selComp);
                end
                oldPos = poly.getPosition();

                newPos = [];
                switch eventdata.Key
                    case 'rightarrow'
                        tfm = [0.01,0];
                        newPos = oldPos + tfm;
                    case 'leftarrow'
                        tfm = [-0.01,0];
                        newPos = oldPos + tfm;
                    case 'uparrow'
                        tfm = [0,0.01];
                        newPos = oldPos + tfm;
                    case 'downarrow'
                        tfm = [0,-0.01];
                        newPos = oldPos + tfm;
                    case 'c'
                        if(ismember('control',eventdata.Modifier))
                            try
                                if(not(isa(selComp,'NNS_AbstractHull')) && isa(selComp,'NNS_VehicleComponent'))
                                    copiedComp = selComp.copy();
                                    copiedComp.relPos = [0;0];
                                    addVehCompToShip(app, copiedComp, ship, shpGrp, handles);
                                end
                            catch ME
                                warndlg('The selected ship component could not be copied.');
                            end
                        end
                    case 'delete'
                        if(poly.Deletable)
                            controllers = ship.components.getControllerComps();

                            allSubs = NNS_ControllerSubroutine.empty(0,0);
                            for(i=1:length(controllers))
                                controller = controllers(i);

                                if(isa(controller, 'NNS_BasicShipController'))
                                    allSubs = horzcat(allSubs, controller.getAllSubroutines()); %#ok<AGROW>
                                end
                            end

                            inUse = false;
                            for(i=1:length(allSubs))
                                sub = allSubs(i);

                                for(j=1:length(sub.operations))
                                    op = sub.operations(j);

                                    comp = op.getComponent();
                                    if(isa(comp,'NNS_Ship'))
                                        comp = comp.hull;
                                    end

                                    if(selComp == comp)
                                        inUse = true;
                                        break;
                                    end

                                    numerics = op.getNumeric();
                                    if(not(isempty(numerics)))
                                        for(k=1:length(numerics))
                                            numeric = numerics(k);

                                            if(isa(numeric,'NNS_ControllerComponentParameter'))
                                                numericComp = numeric.getComponent();

                                                if(selComp == numericComp)
                                                    inUse = true;
                                                    break;
                                                end
                                            end
                                        end
                                    end
                                end
                            end

                            if(inUse == false)
                                delete(poly);

                                intersectionExceptions = getappdata(handles.shipCompEditorGUI,'intersectionExceptions');
                                updateCPolyEdgesBasedOnValidness(app, shpGrp, intersectionExceptions)
                            else
                                warndlg('A component cannot be deleted if it is in use in a operation or conditional block or parameter in a subroutine.','Cannot Delete Variable','modal');
                            end
                        end
                end

                if(not(isempty(newPos)))
                    poly.setPosition(newPos);
                end

                updateDataDisplays(handles);
            end
        end

        % Selection changed function: CompTree
        function CompTreeSelectionChanged(app, event)
            [~, ~, handles] = convertToGUIDECallbackArguments(app, event);
            selectedNode = app.CompTree.SelectedNodes;

            if(strcmpi(app.shipCompEditorGUI.SelectionType,'normal'))
                %                 contents = cellstr(get(hObject,'String'));
                %                 sel = strip(contents{get(hObject,'Value')});
                sel = selectedNode.Text;

                shpGrp = getappdata(app.shipCompEditorGUI,'shipGraphics');
                ship = getappdata(app.shipCompEditorGUI,'ship');

                newComp = NNS_ComponentHierarchy.getDefaultComponentForTypeName(sel, ship);
                if(not(isempty(newComp)))
                    addVehCompToShip(app, newComp, ship, shpGrp, handles);

                    intersectionExceptions = getappdata(app.shipCompEditorGUI,'intersectionExceptions');
                    updateCPolyEdgesBasedOnValidness(app, shpGrp, intersectionExceptions);
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.shipCompEditorGUI = uifigure('Visible', 'off');
            app.shipCompEditorGUI.Name = 'Ship Component Editor';
            app.shipCompEditorGUI.CloseRequestFcn = createCallbackFcn(app, @shipCompEditorGUI_CloseRequestFcn, true);
            app.shipCompEditorGUI.WindowButtonDownFcn = createCallbackFcn(app, @shipCompEditorGUI_WindowButtonDownFcn, true);
            app.shipCompEditorGUI.WindowButtonMotionFcn = createCallbackFcn(app, @shipCompEditorGUI_WindowButtonMotionFcn, true);
            app.shipCompEditorGUI.WindowKeyPressFcn = createCallbackFcn(app, @shipCompEditorGUI_WindowKeyPressFcn, true);
            app.shipCompEditorGUI.Tag = 'shipCompEditorGUI';
            app.shipInfoMenu = uimenu(app.shipCompEditorGUI);
            app.shipInfoMenu.Text = 'Ship Info';
            app.shipInfoMenu.Tag = 'shipInfoMenu';
            app.setShipNameAndDesc = uimenu(app.shipInfoMenu);
            app.setShipNameAndDesc.MenuSelectedFcn = createCallbackFcn(app, @setShipNameAndDesc_Callback, true);
            app.setShipNameAndDesc.Text = 'Set Name and Description';
            app.setShipNameAndDesc.Tag = 'setShipNameAndDesc';
            app.GridLayout = uigridlayout(app.shipCompEditorGUI);
            app.titleText = uilabel(app.GridLayout);
            app.titleText.Tag = 'titleText';
            app.titleText.Text = 'Ship Component Editor';
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.shipCanvasAxes = uiaxes(app.GridLayout2);
            app.shipCanvasAxes.XLim = [-5 5];
            app.shipCanvasAxes.YLim = [-5 5];
            app.shipCanvasAxes.ALim = [0 1];
            app.shipCanvasAxes.XTick = [-5 -4 -3 -2 -1 0 1 2 3 4 5];
            app.shipCanvasAxes.XTickLabel = {};
            app.shipCanvasAxes.YTick = [-5 -4 -3 -2 -1 0 1 2 3 4 5];
            app.shipCanvasAxes.YTickLabel = {};
            app.shipCanvasAxes.Tag = 'shipCanvasAxes';
            app.GridLayout3 = uigridlayout(app.GridLayout2);
            app.text2 = uilabel(app.GridLayout3);
            app.text2.Tag = 'text2';
            app.text2.Text = 'Component Palette';
            app.editPidControllersButton = uibutton(app.GridLayout3, 'push');
            app.editPidControllersButton.ButtonPushedFcn = createCallbackFcn(app, @editPidControllersButton_Callback, true);
            app.editPidControllersButton.Tag = 'editPidControllersButton';
            app.editPidControllersButton.Tooltip = 'A tool for tuning your ship''s onboard PID Controllers.';
            app.editPidControllersButton.Text = 'Edit PID Controllers';
            app.saveAndCloseButton = uibutton(app.GridLayout3, 'push');
            app.saveAndCloseButton.ButtonPushedFcn = createCallbackFcn(app, @saveAndCloseButton_Callback, true);
            app.saveAndCloseButton.Tag = 'saveAndCloseButton';
            app.saveAndCloseButton.Text = 'Save and Close';
            app.CompTree = uitree(app.GridLayout3);
            app.CompTree.SelectionChangedFcn = createCallbackFcn(app, @CompTreeSelectionChanged, true);
            app.GridLayout4 = uigridlayout(app.GridLayout2);
            app.uipanel2 = uipanel(app.GridLayout4);
            app.uipanel2.Title = 'Vehicle Data';
            app.uipanel2.Tag = 'uipanel2';
            app.GridLayout5 = uigridlayout(app.uipanel2);
            app.uipanel5 = uipanel(app.GridLayout5);
            app.uipanel5.Title = 'Vehicle Dynamics Summary';
            app.uipanel5.Tag = 'uipanel5';
            app.GridLayout6 = uigridlayout(app.uipanel5);
            app.vehDynDataText = uilabel(app.GridLayout6);
            app.vehDynDataText.Tag = 'vehDynDataText';
            app.vehDynDataText.Text = {'Total Mass =           100000000 kg'; 'Thrust Avail. =       500000 N'; 'Max. Accel. =          1.00000 m/s/s'; 'Max. Speed =          5.00000 m/s'; '----------------------------------'; 'Mom. of Inert. =   10000 kg*m^2'; 'Torque Avail. =     500000 N-m'; 'Max. Rot. Accel. = 1.00000 deg/s/s'; 'Max. Rot. Rate    = 5.00000 deg/s'};
            app.uipanel6 = uipanel(app.GridLayout5);
            app.uipanel6.Title = 'Vehicle Power Summary';
            app.uipanel6.Tag = 'uipanel6';
            app.GridLayout7 = uigridlayout(app.uipanel6);
            app.vehPowerDataText = uilabel(app.GridLayout7);
            app.vehPowerDataText.Tag = 'vehPowerDataText';
            app.vehPowerDataText.Tooltip = 'Displays the total power generated and used onboard the ship.  The power margin must be positive for the ship to be valid.';
            app.vehPowerDataText.Text = {'Power Generated = 1000 kW'; 'Max. Power Draw = 900 kW'; 'Power Margin   =  100 kW'};
            app.selCompSumPanel = uipanel(app.GridLayout5);
            app.selCompSumPanel.Title = 'Selected Component Summary';
            app.selCompSumPanel.Tag = 'selCompSumPanel';
            app.GridLayout8 = uigridlayout(app.selCompSumPanel);
            app.selectedCompSumText = uilabel(app.GridLayout8);
            app.selectedCompSumText.Tag = 'selectedCompSumText';
            app.selectedCompSumText.Text = {'To'; 'Be'; 'Filled'; 'In'};
            app.editSelCompButton = uibutton(app.GridLayout4, 'push');
            app.editSelCompButton.ButtonPushedFcn = createCallbackFcn(app, @editSelCompButton_Callback, true);
            app.editSelCompButton.Tag = 'editSelCompButton';
            app.editSelCompButton.Text = 'Edit Selected Component (COMP TYPE #N)';
            % Show the figure after all components are created
            app.shipCompEditorGUI.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = shipCompEditorGUI_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.shipCompEditorGUI)

                % Execute the startup function
                runStartupFcn(app, @(app)shipCompEditorGUI_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.shipCompEditorGUI)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.shipCompEditorGUI)
        end
    end
end