classdef mainGUI_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        mainGUI            matlab.ui.Figure
        GridLayout         matlab.ui.container.GridLayout
        startBattleButton  matlab.ui.control.Button
        uipanel2           matlab.ui.container.Panel
        GridLayout4        matlab.ui.container.GridLayout
        text3              matlab.ui.control.Label
        uipanel1           matlab.ui.container.Panel
        GridLayout2        matlab.ui.container.GridLayout
        GridLayout3        matlab.ui.container.GridLayout
        removeShipButton   matlab.ui.control.Button
        saveAsButton       matlab.ui.control.Button
        saveShipButton     matlab.ui.control.Button
        editShipButton     matlab.ui.control.Button
        loadShipButton     matlab.ui.control.Button
        newShipButton      matlab.ui.control.Button
        loadedShipsTable   matlab.ui.control.Table
        text2              matlab.ui.control.Label
    end


    methods (Access = private)
        function ship = getSelectedShip(app, handles)
            loadedShips = getappdata(app.mainGUI,'loadedShips');
            tableSel = app.loadedShipsTable.UserData;

            ship = NNS_Ship.empty(0,0);
            if(width(tableSel) == 2)
                selRow = tableSel(1,1);

                if(selRow <= length(loadedShips))
                    ship = loadedShips{selRow};
                end
            end
        end

        function refreshShipTable(app, handles)
            loadedShips = getappdata(app.mainGUI,'loadedShips');

            tableData = cell(0,3);
            for(i=1:length(loadedShips)) %#ok<*NO4LP>
                ship = loadedShips{i};

                if(not(isempty(ship)))
                    changedStr = '';
                    if(ship.changed)
                        changedStr = '*';
                    end

                    tableData(end+1,:) = {[ship.name,changedStr],ship.team.str,ship.hull.getMaxHitPoints()}; %#ok<AGROW>
                end
            end
            app.loadedShipsTable.Data = tableData;
        end

        function updateButtonsBasedOnTableState(app, handles)
            tableSel = app.loadedShipsTable.UserData;

            if(isempty(tableSel))
                app.editShipButton.Enable = 'off';
                app.saveShipButton.Enable = 'off';
                app.saveAsButton.Enable = 'off';
                app.removeShipButton.Enable = 'off';
            else
                app.editShipButton.Enable = 'on';
                app.saveShipButton.Enable = 'on';
                app.saveAsButton.Enable = 'on';
                app.removeShipButton.Enable = 'on';
            end
        end

        % Update components that require runtime configuration
        function addRuntimeConfigurations(app)

            % Set component properties that require runtime configuration
            app.loadedShipsTable.BackgroundColor = [1 1 1;0.9412 0.9412 0.9412];
            app.loadedShipsTable.ColumnFormat = {'char' {'<none>' 'Red' 'Green' 'Blue' 'Black'} 'bank'};
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function mainGUI_OpeningFcn(app, varargin)
            % Add runtime required configuration - Added by Migration Tool
            addRuntimeConfigurations(app);

            % Ensure that the app appears on screen when run
            movegui(app.mainGUI, 'center');

            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>

            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to mainGUI (see VARARGIN)

            % Choose default command line output for mainGUI
            handles.output = hObject;

            arena = NNS_Arena(2*[-25 25], 2*[-25 25]);

            setappdata(hObject,'arena',arena);
            setappdata(hObject,'loadedShips',{});
            handles.loadedShipsTable.Data = cell(0,size(handles.loadedShipsTable.Data,2));
            updateButtonsBasedOnTableState(app, handles);

            % Update handles structure
            guidata(hObject, handles);
        end

        % Button pushed function: editShipButton
        function editShipButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to editShipButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            ship = getSelectedShip(app, handles);

            set(handles.mainGUI,'Visible','off');
            %                 ship = shipCompEditorGUI(ship);
            shipCompEditorGUI_App(ship);
            ship.changed = true;
            set(handles.mainGUI,'Visible','on');

            loadedShips = getappdata(handles.mainGUI,'loadedShips');
            tableSel = handles.loadedShipsTable.UserData;
            if(width(tableSel) == 2)
                selRow = tableSel(1);

                if(selRow <= length(loadedShips))
                    loadedShips{tableSel(1)} = ship;
                end

                setappdata(handles.mainGUI,'loadedShips',loadedShips);

                refreshShipTable(app, handles);
            end
        end

        % Button pushed function: loadShipButton
        function loadShipButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to loadShipButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            [FileName,PathName] = uigetfile({'*.mat','Roboships Ship File (*.mat)'},...
                'Load Ship',...
                'ship.mat');
            filePath = [PathName,FileName];

            if(not(FileName == 0))
                msg = sprintf('Loading %s, please wait...', FileName);
                hPrgDlg = uiprogressdlg(app.mainGUI, "Icon","info", "Indeterminate","on", "Message",msg, "Title","Roboships");

                load(filePath,'ship');
                if(exist('ship','var'))
                    ship.team = NNS_ShipTeam.None;

                    loadedShips = getappdata(handles.mainGUI,'loadedShips');
                    loadedShips{end+1} = ship;
                    setappdata(handles.mainGUI,'loadedShips',loadedShips);

                    refreshShipTable(app, handles);
                end

                close(hPrgDlg);
            end
        end

        % Cell edit callback: loadedShipsTable
        function loadedShipsTable_CellEditCallback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to loadedShipsTable (see GCBO)
            % eventdata  structure with the following fields (see MATLAB.UI.CONTROL.TABLE)
            %	Indices: row and column indices of the cell(s) edited
            %	PreviousData: previous data for the cell(s) edited
            %	EditData: string(s) entered by the user
            %	NewData: EditData or its converted form set on the Data property. Empty if Data was not changed
            %	Error: error string when failed to convert EditData to appropriate value for Data
            % handles    structure with handles and user data (see GUIDATA)
            loadedShips = getappdata(handles.mainGUI,'loadedShips');
            row = eventdata.Indices(1);
            ship = loadedShips{row};

            if(eventdata.Indices(2) == 2)
                ship.team = NNS_ShipTeam.getTeamForStr(eventdata.NewData);
            end
        end

        % Cell selection callback: loadedShipsTable
        function loadedShipsTable_CellSelectionCallback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event);

            % hObject    handle to loadedShipsTable (see GCBO)
            % eventdata  structure with the following fields (see MATLAB.UI.CONTROL.TABLE)
            %	Indices: row and column indices of the cell(s) currently selecteds
            % handles    structure with handles and user data (see GUIDATA)
            hObject.UserData = eventdata.Indices;
            updateButtonsBasedOnTableState(app, handles);
        end

        % Button pushed function: newShipButton
        function newShipButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to newShipButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            msg = sprintf('Creating new ship, please wait...');
            hPrgDlg = uiprogressdlg(app.mainGUI, "Icon","info", "Indeterminate","on", "Message",msg, "Title","Roboships");

            arena = getappdata(handles.mainGUI,'arena');
            loadedShips = getappdata(handles.mainGUI,'loadedShips');
            loadedShips{end+1} = NNS_Ship.createDefaultBasicShip(arena);
            setappdata(handles.mainGUI,'loadedShips',loadedShips);

            refreshShipTable(app, handles);

            close(hPrgDlg);
        end

        % Button pushed function: removeShipButton
        function removeShipButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to removeShipButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            loadedShips = getappdata(handles.mainGUI,'loadedShips');
            tableSel = handles.loadedShipsTable.UserData;
            if(width(tableSel) == 2)
                selRow = tableSel(1);

                if(selRow <= length(loadedShips))
                    loadedShips(tableSel(1)) = [];
                end

                setappdata(handles.mainGUI,'loadedShips',loadedShips);

                refreshShipTable(app, handles);
            end
        end

        % Button pushed function: saveAsButton
        function saveAsButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to saveAsButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            ship = getSelectedShip(app, handles);

            if(not(isempty(ship)))
                [FileName,PathName,~] = uiputfile({'*.mat','Roboships Ship File (*.mat)'},...
                    'Save Ship','ship.mat');

                if(not(FileName == 0))
                    filePath = [PathName,FileName];
                    ship.savedToPath = filePath;
                    ship.changed = false;
                    save(filePath,'ship');

                    refreshShipTable(app, handles);
                end
            end
        end

        % Button pushed function: saveShipButton
        function saveShipButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to saveShipButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            ship = getSelectedShip(app, handles);

            if(not(isempty(ship)))
                if(isprop(ship,'savedToPath') && not(isempty(ship.savedToPath)))
                    ship.changed = false;
                    save(ship.savedToPath,'ship');

                    refreshShipTable(app, handles);
                else
                    evt = matlab.ui.eventdata.ValueChangedData(0, 0);
                    saveAsButton_Callback(app, evt);
                end
            end
        end

        % Button pushed function: startBattleButton
        function startBattleButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            showGraphics = true;

            arena = getappdata(handles.mainGUI,'arena');
            loadedShips = getappdata(handles.mainGUI,'loadedShips');

            arena.propObjs.removeAllPropObjs();

            for(i=1:length(loadedShips))
                ship = loadedShips{i};
                ship.id = rand();
                ship.team = NNS_ShipTeam.getTeamForStr(handles.loadedShipsTable.Data{i,2});
                ship.arena = arena;
                ship.stateMgr.setRandomizedPositionAndHeading([arena.xLims, arena.yLims],[0 360]);
                arena.propObjs.addPropagatedObject(ship);
            end

            shipPlacement = NNS_CircularShipPlacement();
            simDriver = NNS_SimulationDriver(arena,shipPlacement,showGraphics);
            arena.simClock = simDriver.clock;
            try
                if(~isdeployed())
%                     profile off; profile('on','-historysize',500000000);
                end

                simDriver.driveSimulation();

                if(~isdeployed())
%                     profile viewer;
                end
            catch ME
                delete(timerfindall);
                throw(ME);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.mainGUI = uifigure('Visible', 'off');
            app.mainGUI.Name = 'Roboships';
            app.mainGUI.Tag = 'mainGUI';
            app.GridLayout = uigridlayout(app.mainGUI);
            app.text2 = uilabel(app.GridLayout);
            app.text2.Tag = 'text2';
            app.text2.Text = 'RoboShips';
            app.uipanel1 = uipanel(app.GridLayout);
            app.uipanel1.Title = 'Ships';
            app.uipanel1.Tag = 'uipanel1';
            app.GridLayout2 = uigridlayout(app.uipanel1);
            app.loadedShipsTable = uitable(app.GridLayout2);
            app.loadedShipsTable.ColumnName = {'Ship'; 'Team'; 'Hit Points'};
            app.loadedShipsTable.RowName = '';
            app.loadedShipsTable.SelectionType = 'row';
            app.loadedShipsTable.ColumnEditable = [false true false];
            app.loadedShipsTable.CellEditCallback = createCallbackFcn(app, @loadedShipsTable_CellEditCallback, true);
            app.loadedShipsTable.CellSelectionCallback = createCallbackFcn(app, @loadedShipsTable_CellSelectionCallback, true);
            app.loadedShipsTable.Tag = 'loadedShipsTable';
            app.GridLayout3 = uigridlayout(app.GridLayout2);
            app.newShipButton = uibutton(app.GridLayout3, 'push');
            app.newShipButton.ButtonPushedFcn = createCallbackFcn(app, @newShipButton_Callback, true);
            app.newShipButton.Tag = 'newShipButton';
            app.newShipButton.Text = 'New';
            app.loadShipButton = uibutton(app.GridLayout3, 'push');
            app.loadShipButton.ButtonPushedFcn = createCallbackFcn(app, @loadShipButton_Callback, true);
            app.loadShipButton.Tag = 'loadShipButton';
            app.loadShipButton.Text = 'Load';
            app.editShipButton = uibutton(app.GridLayout3, 'push');
            app.editShipButton.ButtonPushedFcn = createCallbackFcn(app, @editShipButton_Callback, true);
            app.editShipButton.Tag = 'editShipButton';
            app.editShipButton.Text = 'Edit';
            app.saveShipButton = uibutton(app.GridLayout3, 'push');
            app.saveShipButton.ButtonPushedFcn = createCallbackFcn(app, @saveShipButton_Callback, true);
            app.saveShipButton.Tag = 'saveShipButton';
            app.saveShipButton.Text = 'Save';
            app.saveAsButton = uibutton(app.GridLayout3, 'push');
            app.saveAsButton.ButtonPushedFcn = createCallbackFcn(app, @saveAsButton_Callback, true);
            app.saveAsButton.Tag = 'saveAsButton';
            app.saveAsButton.Text = 'Save As';
            app.removeShipButton = uibutton(app.GridLayout3, 'push');
            app.removeShipButton.ButtonPushedFcn = createCallbackFcn(app, @removeShipButton_Callback, true);
            app.removeShipButton.Tag = 'removeShipButton';
            app.removeShipButton.Text = 'Remove';
            app.uipanel2 = uipanel(app.GridLayout);
            app.uipanel2.Title = 'Battle Parameters';
            app.uipanel2.Tag = 'uipanel2';
            app.GridLayout4 = uigridlayout(app.uipanel2);
            app.text3 = uilabel(app.GridLayout4);
            app.text3.Tag = 'text3';
            app.text3.Text = 'Battle Duration';
            app.startBattleButton = uibutton(app.GridLayout, 'push');
            app.startBattleButton.ButtonPushedFcn = createCallbackFcn(app, @startBattleButton_Callback, true);
            app.startBattleButton.Tag = 'startBattleButton';
            app.startBattleButton.Text = 'Start Battle!';
            % Show the figure after all components are created
            app.mainGUI.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = mainGUI_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.mainGUI)

                % Execute the startup function
                runStartupFcn(app, @(app)mainGUI_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.mainGUI)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.mainGUI)
        end
    end
end